classdef Diffuser_numerical < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiffuserGUI                     matlab.ui.Figure
        SettingsMenu                    matlab.ui.container.Menu
        ParallelcomputingMenu           matlab.ui.container.Menu
        OffMenu                         matlab.ui.container.Menu
        OnMenu                          matlab.ui.container.Menu
        SeemanualButton                 matlab.ui.control.Button
        VisitsLabel                     matlab.ui.control.Label
        BugreportscanbesenttowlgcugbeducnLabel  matlab.ui.control.Label
        ThisisabetaversionLabel         matlab.ui.control.Label
        TtdistributionsLabel            matlab.ui.control.Label
        SavefiguresButton               matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ImportdataTab                   matlab.ui.container.Tab
        TemplateButton                  matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        InitialconditionsPanel          matlab.ui.container.Panel
        DistancegridEditField           matlab.ui.control.NumericEditField
        DistancegridEditFieldLabel      matlab.ui.control.Label
        CompositionDropDown_2           matlab.ui.control.DropDown
        CompositionDropDown_2Label      matlab.ui.control.Label
        DistanceDropDown_2              matlab.ui.control.DropDown
        DistanceDropDown_2Label         matlab.ui.control.Label
        DataselectionPanel              matlab.ui.container.Panel
        CompositionuncertaintyDropDown  matlab.ui.control.DropDown
        CompositionuncertaintyDropDownLabel  matlab.ui.control.Label
        CompositionDropDown             matlab.ui.control.DropDown
        CompositionDropDownLabel        matlab.ui.control.Label
        DistanceDropDown                matlab.ui.control.DropDown
        DistanceDropDownLabel           matlab.ui.control.Label
        DistanceunitDropDown            matlab.ui.control.DropDown
        DistanceunitDropDownLabel       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        text11                          matlab.ui.control.Label
        RandomButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        CleartableButton                matlab.ui.control.Button
        SelectfileButton                matlab.ui.control.Button
        DeconvolutionoptionalTab        matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        Image_2                         matlab.ui.control.Image
        BeamsizePanel                   matlab.ui.container.Panel
        UnitDropDown                    matlab.ui.control.DropDown
        UnitDropDownLabel               matlab.ui.control.Label
        FWHMLorentzEditField            matlab.ui.control.NumericEditField
        FWHMLorentzEditFieldLabel       matlab.ui.control.Label
        FWHMGaussEditField              matlab.ui.control.NumericEditField
        FWHMGaussEditFieldLabel         matlab.ui.control.Label
        FWHMEditField                   matlab.ui.control.NumericEditField
        FWHMEditFieldLabel              matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        fullwidthathalfmaximiumLabel    matlab.ui.control.Label
        DeconvolutionismodifiedfromLabel  matlab.ui.control.Label
        DeconvoluteanalyticalbeameffectsCheckBox  matlab.ui.control.CheckBox
        BeamshapeButtonGroup            matlab.ui.container.ButtonGroup
        VoigtButton                     matlab.ui.control.ToggleButton
        LorentzButton                   matlab.ui.control.ToggleButton
        GaussButton                     matlab.ui.control.ToggleButton
        RectangleButton                 matlab.ui.control.ToggleButton
        CircularButton                  matlab.ui.control.ToggleButton
        DiffusioncoefficientDTab        matlab.ui.container.Tab
        UseasimpleArrheniusequationDD0expEaRTCheckBox  matlab.ui.control.CheckBox
        DisafunctionofTPfO2andcompositionCheckBox  matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        XAnDropDown                     matlab.ui.control.DropDown
        XAnDropDownLabel                matlab.ui.control.Label
        inplagioclaseLabel              matlab.ui.control.Label
        Dequation                       matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        aSiO2EditField                  matlab.ui.control.NumericEditField
        aSiO2EditFieldLabel             matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        fO2EditField                    matlab.ui.control.NumericEditField
        fO2EditFieldLabel               matlab.ui.control.Label
        barLabel_2                      matlab.ui.control.Label
        barLabel                        matlab.ui.control.Label
        Reference_2                     matlab.ui.control.DropDown
        Element_2                       matlab.ui.control.DropDown
        ExamplesDropDown_2              matlab.ui.control.DropDown
        ExamplesDropDown_2Label         matlab.ui.control.Label
        Panel_1                         matlab.ui.container.Panel
        ExamplesDropDown                matlab.ui.control.DropDown
        ExamplesDropDownLabel           matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        Reference                       matlab.ui.control.DropDown
        Element                         matlab.ui.control.DropDown
        TimecalculationTab              matlab.ui.container.Tab
        PropagatecurvefittingerrorCheckBox  matlab.ui.control.CheckBox
        SaveresultsButton               matlab.ui.control.Button
        CalculatetButton                matlab.ui.control.Button
        PropagateDerrorCheckBox         matlab.ui.control.CheckBox
        MonteCarlotrialsEditField       matlab.ui.control.NumericEditField
        MonteCarlotrialsforestimatingtuncertaintyEditFieldLabel  matlab.ui.control.Label
        TemperatureconditionsPanel      matlab.ui.container.Panel
        Coolingequation                 matlab.ui.control.Label
        InitialtemperatureEditField     matlab.ui.control.NumericEditField
        InitialtemperatureEditFieldLabel  matlab.ui.control.Label
        TemperatureerrorEditField       matlab.ui.control.NumericEditField
        TemperatureerrorEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        sigmaLabel                      matlab.ui.control.Label
        CoolingpathDropDown             matlab.ui.control.DropDown
        CoolingpathDropDownLabel        matlab.ui.control.Label
        errorcontributions              matlab.ui.control.UIAxes
        time                            matlab.ui.control.UIAxes
        curvefitting                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        count
        data
        name
        name2
        DiffCoef
        DiffCoef2
        outputname
        output        
        CovName
        CovName_selected
        Eqlabel
    end
    
    properties (Access = public)
        hx %pulic properties can be used in functions outsite app, like function test(app): disp(app.hx)
        hy
        hs
        ispar %whether starts parallel computing, default=0
        data2save
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isunix && ~ismac
                visits=length(dir('/local/MathWorks/webapps/R2021b/USR/services/mdwas/logs'))+2720;
                app.VisitsLabel.Text=['Visits = ', num2str(visits)];
            end
            app.ispar=0;
            app.count=1;
            app.data2save=[];
            app.UITable.Data=repmat({[]},10,5);
            app.UITable.ColumnFormat={'numeric'};
            %T-t distribution panel--------------------------------------------------------------
            pos=app.DiffuserGUI.Position;
            yxratio=pos(4)/pos(3);
            if ismac
                orgx=0.9225; orgy=0.408; %mac
            else
                orgx=0.4525; orgy=0.208; %Linux
            end
            wdth=0.15; height=wdth/yxratio;
            height2=0.15;
            hf= app.DiffuserGUI;
            app.hx=axes(hf, 'Position',[orgx orgy+height*1.01 wdth height2],'Units','pixels'); %[left bottom width height]
            app.hy=axes(hf, 'Position',[orgx+wdth*1.01 orgy height2*yxratio height],'Units','pixels'); %[left bottom width height]
            app.hs=axes(hf, 'Position',[orgx orgy wdth height],'Units','pixels'); %[left bottom width height]
            linkaxes([app.hx,app.hs],'x')
            linkaxes([app.hy,app.hs],'y')
            xlabel(app.hs,'Temperature (\circC)')
            ylabel(app.hs,'Timescale')

            box(app.hs,'on')
            box(app.hx,'off')
            box(app.hy,'off')
            xticklabels(app.hx,{})
            xticklabels(app.hy,{})
            yticklabels(app.hx,{})
            yticklabels(app.hy,{})
            app.hy.XColor='none';
            app.hx.YColor='none';

            %prepare diffusivity options--------------------------------------------------------------
            %use a simple D=f(T)
            if isunix && ~ismac
                %readcell need direct location of file for web app, e.g., C:\..\..
                raw=readcell('/home/sims/Documents/MATLAB/Diffusion/DiffusionCoefficient.xlsx');
            else
                raw=readcell('DiffusionCoefficient.xlsx');
            end
            app.name=raw(2:end,1:3);
            app.DiffCoef=raw(2:end,4:8);
            index=cellfun(@(x) isa(x,'missing'), app.DiffCoef);
            app.DiffCoef(index) = {[]};

            %set diffusion coefficients options
            app.ExamplesDropDown.Items=unique(app.name(:,1),'stable');
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            app.Element.Items=unique(elementlist,'stable');
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            app.Reference.Items=referencelist;
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
            app.UITable2.ColumnFormat={'numeric'};

            %use a complex D=f(T,P,fO2,C...)----------------------------------------------------------
            if isunix && ~ismac
                %readcell need direct location of file for web app, e.g., C:\..\..
                raw=readcell('/home/sims/Documents/MATLAB/Diffusion/DiffusionCoefficient_OlPl.xlsx');     
            else
                raw=readcell('DiffusionCoefficient_OlPl.xlsx');
            end
            app.name2=raw(2:end,1:3);
            Ddata=raw(2:end,4:10);
            app.CovName=raw(2:end,11);
            Dlabel=raw(2:end,13:18);
            index=cellfun(@(x) isa(x,'missing'), Ddata);
            Ddata(index) = {0};
            app.DiffCoef2=cell2mat(Ddata);

            %set diffusion coefficients options
            app.ExamplesDropDown_2.Items=unique(app.name2(:,1),'stable');
            mineral = app.ExamplesDropDown_2.Value;
            mineral_site=find(strcmp(app.name2(:,1),mineral));
            elementlist=app.name2(mineral_site,2);
            app.Element_2.Items=unique(elementlist,'stable');
            element=app.Element_2.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name2(element_site,3);
            app.Reference_2.Items=referencelist;
            reference=app.Reference_2.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable4.Data=app.DiffCoef2(reference_site(1),:);
            app.UITable4.ColumnFormat={'numeric'};
            %equation label
            Dlabel2=strcat(repmat({'b*','c*','d*','e*','f*','g*'},size(Dlabel,1),1),Dlabel);
            label=join(Dlabel2,'+');
            app.Eqlabel=strcat(repmat({'ln[D]=a+'},size(label,1),1),label);
            app.Dequation.Text=app.Eqlabel(reference_site(1));
            %covariance matrix
            app.CovName_selected=app.CovName{reference_site(1)};

            %check if fO2 is needed
            if app.DiffCoef2(reference_site(1),2)~=0
                app.fO2EditField.Enable='on';
            else
                app.fO2EditField.Enable='off';
            end
            %check if pressure is needed
            if app.DiffCoef2(reference_site(1),5)~=0 || app.DiffCoef2(reference_site(1),6)~=0
                app.PressureEditField.Enable='on';
            else
                app.PressureEditField.Enable='off';
            end
            %check if aSiO2 is needed
            if app.DiffCoef2(reference_site(1),7)~=0
                app.aSiO2EditField.Enable='on';
            else
                app.aSiO2EditField.Enable='off';
            end
            %check if XAn is needed
            if reference_site(1)>27
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDown.Enable='off';
            end
            %check if element is Mn or Ni in olivine
            if ismember(reference_site(1),[7:12,19:24])
                app.XAnDropDownLabel.Text='XFo';
                app.inplagioclaseLabel.Text='in olivine';
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDownLabel.Text='XAn';
                app.inplagioclaseLabel.Text='in plagioclase';
            end

            %Tooltip-------------------------------------------------------------------------------------------------------------
            %import data panel
            app.SelectfileButton.Tooltip='Import data from a spreadsheet or delimited text';
            app.RandomButton.Tooltip='Generate a random diffusion profile';
            app.TemplateButton.Tooltip='Download a template spreadsheet';
            app.CleartableButton.Tooltip='Clear the current table and plot';
            app.DataselectionPanel.Tooltip='Select which column represents the measured diffusion profile';
            app.DistanceunitDropDown.Tooltip='Choose the distance unit of the input diffusion profile. The unit of the initial condition is treated as the same.';
            app.DistanceDropDown.Tooltip='Choose which column represents the distance data of the diffusion profile';
            app.CompositionDropDown.Tooltip='Choose which column represents the composition data of the diffusion profile';
            app.CompositionuncertaintyDropDown.Tooltip='Choose which column represents the composition uncertainty data of the diffusion profile';
            app.InitialconditionsPanel.Tooltip='Select which column represents the assumed initial condition';
            app.DistanceDropDown_2.Tooltip='Choose which column represents the distance data of the initial condition';
            app.CompositionDropDown_2.Tooltip='Choose which column represents the composition data of the initial condition';
            app.DistancegridEditField.Tooltip='Set the grid points for dividing the distance of initial conditions (more points cost more time)';
            app.PlotButton.Tooltip='Plot the measured diffusion profile and initial conditions';
            
            %deconvolution panel
            app.DeconvolutionoptionalTab.Tooltip='This is an optional function to deconvolute the possible analytical beam effect';
            app.DeconvoluteanalyticalbeameffectsCheckBox.Tooltip=['Tick to deconvolute the beam effect'];
            app.BeamshapeButtonGroup.Tooltip='Choose the analytical beam shape';
            app.BeamsizePanel.Tooltip=['Set the beam size and unit:', newline, ...
                'set diameter for a circular/elliptical beam shape; ', newline, ...
                'set width for a square/rectangular beam shape; ', newline, ...
                'set FWHM for a Gaussian or Lorentzian beam shape; ', newline, ...
                'set FWHM Gauss and FWHM Lorentz for a pseudo-Voigt beam shape.'];

            %diffusion coefficient panel
            app.DiffusioncoefficientDTab.Tooltip='Set parameters of the diffusion coefficient with an internally consistent database in Diffuser (accessible on github)';
            app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Tooltip='Tick if D can be expressed as a simple Arrhenius equation';
            app.ExamplesDropDown.Tooltip='Choose the analyzed mineral';
            app.Element.Tooltip='Choose the analyzed element';
            app.Reference.Tooltip='Choose the reference of D which may be determined by different studies';
            app.UITable2.Tooltip='Input the D0 and E parameters (automatically filled if using the internal database)';
            
            app.DisafunctionofTPfO2andcompositionCheckBox.Tooltip='Tick if D is expressed as a function of T, P, fO2, and composition (current available for olivine and plagioclase)';
            app.ExamplesDropDown_2.Tooltip='Choose the analyzed mineral';
            app.Element_2.Tooltip='Choose the analyzed element';
            app.Reference_2.Tooltip='Choose the reference of D which may be determined by different studies';
            app.UITable4.Tooltip='D parameters (automatically filled by using the internal database)';

            app.Dequation.Tooltip='This shows the unified D equation for olivine or plagioclase in Mutch et al. (2021)';
            app.PressureEditField.Tooltip='Set the pressure if editable';
            app.fO2EditField.Tooltip='Set the oxygen fugacity if editable';
            app.aSiO2EditField.Tooltip='Set the SiO2 activity if editable';
            app.XAnDropDown.Tooltip='Choose which column of the input data represents the anorthite content in the plagioclase along the diffusion profile';
            
            %time calculation panel
            app.TemperatureconditionsPanel.Tooltip='Set parameters of temperature for diffusion';
            app.InitialtemperatureEditField.Tooltip='Input the initial temperature when the diffusion starts';
            app.TemperatureerrorEditField.Tooltip='Input the error of the initial temperature';
            app.CoolingpathDropDown.Tooltip='Set the cooling path for the diffusion process, e.g., isothermal, linear, exponential, or parabolic';
            app.MonteCarlotrialsEditField.Tooltip='Input the trials for Monte Carlo modeling in calculating the timescale and its uncertainty';
             app.PropagateDerrorCheckBox.Tooltip='Tick to propagate D uncertainty into timescale uncertainty';
            app.CalculatetButton.Tooltip='Output the calculated diffusion timescale';

            %other
            app.SeemanualButton.Tooltip='Download Diffuser manual';
            app.SavefiguresButton.Tooltip='Download a combined pdf of all current figures';
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            [filename, pathname]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},'Select a excel','MultiSelect','off');
            if ~pathname
                return
            end
            str=fullfile(pathname,filename);
            %not works for web app==============================================================
%             [~,~,extension]=fileparts(filename);
%             switch extension
%                 case {'.txt','.csv'}
%                     app.data=readmatrix(str);
%                 case {'.xlsx','.xls'}
%                     sheets = sheetnames(str);
%                     cs=length(sheets);
%                     if cs==1
%                         sheet=sheets{1};
%                         v=1;
%                     else
%                         [st,v]=listdlg('PromptString','Select a sheet','SelectionMode','single','ListString',sheets);
%                         sheet=sheets{st};
%                     end
%                     if v
%                         app.data=readmatrix(str,'Sheet',sheet);
%                     end
%                 otherwise
%                     return
%             end
            %==============================================================
            app.data=readmatrix(str);
            cdata=num2cell(app.data);
            cdata(cellfun(@isnan, cdata))={[]};
            app.UITable.Data=cdata;
            app.UITable.ColumnName='numbered';

            columnames=strcat({'Column '}, cellstr(string(1:size(cdata,2))));
            app.DistanceDropDown.Items=columnames;
            app.CompositionDropDown.Items=columnames;
            app.CompositionuncertaintyDropDown.Items=['None',columnames];
            app.DistanceDropDown_2.Items=columnames;
            app.CompositionDropDown_2.Items=columnames;
            app.XAnDropDown.Items=columnames;
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            global profile_x profile_C profile_eC initial_x initial_C
            C1=0.70; C2=1; C3=0.80;
            Dt=1e-10;
            h=20*1e-6;
            dt=1e-8;
            switch mod(app.count,12)
                case 1
                    x=(0:3:60)'*1e-6;
                    C=(C2-C1)*erf(x/2/sqrt(Dt))+C1;
                    x=x+15e-6;
                    initial_x=[min(x);min(x)+dt;max(x)];
                    initial_C=[C1;C2;C2];
                case 2
                    x=(-60:3:0)'*1e-6;
                    C=(C2-C1)*erf(-x/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);max(x)-dt;max(x)];
                    initial_C=[C2;C2;C1];
                case 3
                    x=(0:3:60)'*1e-6;
                    C=(C2-C1)*erfc(x/2/sqrt(Dt))+C1;
                    x=x+15e-6;
                    initial_x=[min(x);min(x)+dt;max(x)];
                    initial_C=[C2;C1;C1];
                case 4
                    x=(-60:3:0)'*1e-6;
                    C=(C2-C1)*erfc(-x/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);max(x)-dt;max(x)];
                    initial_C=[C1;C1;C2];
                case 5
                    h=60*1e-6;
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)*(erf((h+x)/2/sqrt(Dt))+erf((h-x)/2/sqrt(Dt))-1)+C1;
                    x=x+75e-6;
                    initial_x=[min(x);min(x)+dt;max(x)-dt;max(x)];
                    initial_C=[C1;C2;C2;C1];
                case 6
                    h=60*1e-6;
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)*(erfc((h+x)/2/sqrt(Dt))+erfc((h-x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);min(x)+dt;max(x)-dt;max(x)];
                    initial_C=[C2;C1;C1;C2];
                case 7
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(1+erf((x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);mean(x)-dt;mean(x);max(x)];
                    initial_C=[C1;C1;C2;C2];
                case 8
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*erfc((x)/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);mean(x)-dt;mean(x);max(x)];
                    initial_C=[C2;C2;C1;C1];
                case 9
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(erf((h-x)/2/sqrt(Dt))+erf((h+x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);mean(x)-h-dt;mean(x)-h;mean(x)+h;mean(x)+h+dt;max(x)];
                    initial_C=[C1;C1;C2;C2;C1;C1];
                case 10
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C1)/2*(erfc((h-x)/2/sqrt(Dt))+erfc((h+x)/2/sqrt(Dt)))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);mean(x)-h-dt;mean(x)-h;mean(x)+h;mean(x)+h+dt;max(x)];
                    initial_C=[C2;C2;C1;C1;C2;C2];
                case 11
                    x=(-60:3:60)'*1e-6;
                    C=(C2-C3)/2*erf((h-x)/2/sqrt(Dt))+(C2-C1)/2*erf((h+x)/2/sqrt(Dt))+(C1+C3)/2;
                    x=x+75e-6;
                    initial_x=[min(x);mean(x)-h-dt;mean(x)-h;mean(x)+h;mean(x)+h+dt;max(x)];
                    initial_C=[C1;C1;C2;C2;C3;C3];
                case 0
                    x=(-60:3:60)'*1e-6;
                    C=(C3-C1)/2*erfc((h-x)/2/sqrt(Dt))+(C2-C1)/2*erfc((h+x)/2/sqrt(Dt))+C1;
                    x=x+75e-6;
                    initial_x=[min(x);mean(x)-h-dt;mean(x)-h;mean(x)+h;mean(x)+h+dt;max(x)];
                    initial_C=[C2;C2;C1;C1;C3;C3];
            end
            temp=rand(length(x),1)*2-1; %[-1,1]
            profile_C=C+temp*0.03;
            profile_eC=[];
            profile_x=x;
            alldata=NaN(length(profile_x),4);
            alldata(:,1:2)=[profile_x,profile_C];
            alldata(1:length(initial_x),3:4)=[initial_x,initial_C];
            app.data=alldata;
            cdata=num2cell(app.data);
            cdata(cellfun(@isnan, cdata))={[]};
            app.UITable.Data=cdata;
            app.UITable.ColumnName='numbered';

            app.DistanceunitDropDown.Value='m';
            app.count=app.count+1;

%             writematrix([profile_x,profile_C],'test.txt',"Delimiter",'tab');
            %interpolate initial conditions
            Nx=length(profile_C)*10; %number of intervals
            Lx=max(initial_x)-min(initial_x); %diffusion profile length
            dx=Lx/Nx; %grid size in x
            x_grid=min(initial_x)+(0:Nx)*dx; %x values on the grid
            x_grid=x_grid';
            initial_C_grid = interp1(initial_x,initial_C,x_grid,"linear","extrap");

            %plot data

            plot(app.curvefitting, profile_x, profile_C, 'LineStyle','none','Marker','o','MarkerEdgeColor',[0.5, 0.5, 0.5]);

            hold(app.curvefitting,'on')
            plot(app.curvefitting,x_grid,initial_C_grid,'k-');
            hold(app.curvefitting,'off')
            legend(app.curvefitting,'measured data','initial condition','Location','best')
        end

        % Button pushed function: TemplateButton
        function TemplateButtonPushed(app, event)
            if isunix && ~ismac
                web('/home/sims/Documents/MATLAB/Diffusion/Diffuser_numerical_example.xlsx')
            end
        end

        % Button pushed function: CleartableButton
        function CleartableButtonPushed(app, event)
            app.UITable.Data=repmat({[]},10,5);
            cla(app.curvefitting)
            yyaxis(app.curvefitting,"right")
            cla(app.curvefitting)
            set(app.curvefitting,'Ycolor','k');
            yticks(app.curvefitting,[])
            yyaxis(app.curvefitting,"left")
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global profile_x profile_C profile_eC initial_x initial_C
            cla(app.curvefitting)
            yyaxis(app.curvefitting,"right")
            cla(app.curvefitting)
            set(app.curvefitting,'Ycolor','k');
            yticks(app.curvefitting,[])
            yyaxis(app.curvefitting,"left")

            columnname=app.DistanceDropDown.Value;
            index=regexp(columnname,'\d*','match');
            profile_x=app.data(:,str2double(index{1}));
            [profile_x,I]=sort(profile_x);
            columnname=app.CompositionDropDown.Value;
            index=regexp(columnname,'\d*','match');
            profile_C=app.data(:,str2double(index{1}));
            profile_C=profile_C(I);
            %delete NaN values
            in=isnan(profile_x); profile_C(in)=[]; profile_x(in)=[];
            in=isnan(profile_C); profile_x(in)=[]; profile_C(in)=[];

            columnname=app.DistanceDropDown_2.Value;
            index=regexp(columnname,'\d','match');
            initial_x=app.data(:,str2double(index{1}));
            initial_x(isnan(initial_x))=[];
            columnname=app.CompositionDropDown_2.Value;
            index=regexp(columnname,'\d','match');
            initial_C=app.data(:,str2double(index{1}));
            initial_C(isnan(initial_C))=[];
            %delete NaN values
            in=isnan(initial_x); initial_C(in)=[]; initial_x(in)=[];
            in=isnan(initial_C); initial_x(in)=[]; initial_C(in)=[];

%             %make sure the intial_x covers the profile_x
%             if min(initial_x)>min(profile_x) || max(initial_x)<max(profile_x)
%                 uialert(app.DiffuserGUI,'The initial distance should cover the profile distance, please check','Wrong')
%                 return
%             end
            %distance unit
            xunit=app.DistanceunitDropDown.Value;
            switch xunit
                case 'nm'
                    factor=1e9;
                case 'um'
                    factor=1e6;
                case 'mm'
                    factor=1e3;
                case 'm'
                    factor=1;
            end
            profile_x=profile_x/factor;
            initial_x=initial_x/factor;
            %interpolate initial conditions
            Nx=app.DistancegridEditField.Value; %number of intervals
            Lx=max(initial_x)-min(initial_x); %diffusion profile length
            dx=Lx/Nx; %grid size in x
            x_grid=min(initial_x)+(0:Nx)*dx; %x values on the grid
            x_grid=x_grid';
            initial_C_grid = interp1(initial_x,initial_C,x_grid,"linear","extrap");

            columnname=app.CompositionuncertaintyDropDown.Value;
            index=regexp(columnname,'\d*','match');
            if isempty(index)
                profile_eC=[];
                plot(app.curvefitting, profile_x, profile_C, 'LineStyle','none','Marker','o','MarkerEdgeColor',[0.5, 0.5, 0.5]);
            else
                profile_eC=app.data(:,str2double(index{1}));
                profile_eC=profile_eC(I);
                h=errorbar(app.curvefitting, profile_x, profile_C, profile_eC);
                h.Marker='.';
                h.MarkerSize=12;
                h.LineStyle='none';
                h.CapSize=0;
            end
            hold(app.curvefitting,'on')
            plot(app.curvefitting,x_grid,initial_C_grid,'k-');
            hold(app.curvefitting,'off')
            legend(app.curvefitting,'measured data','initial condition','Location','best')
        end

        % Value changed function: CoolingpathDropDown
        function CoolingpathDropDownValueChanged(app, event)
            coolingpath = app.CoolingpathDropDown.Value;
            switch coolingpath
                case 'isothermal'
                    app.Coolingequation.Text = 'T=T0';
                    app.time.XLabel.String='Modeled time';
                    xlabel(app.hs,'Temperature (\circC)')
                    app.TtdistributionsLabel.Text='T-t distributions';
                    app.errorcontributions.YLabel.String='ln[t] uncertainty budget';
                case 'linear cooling'
                    app.Coolingequation.Text = 'T=T0-k*t';
                    app.time.XLabel.String='Modeled cooling rate';
                    xlabel(app.hs,'Cooling rate')
                    app.TtdistributionsLabel.Text='rate-t distributions';
                    app.errorcontributions.YLabel.String='Uncertainty budget';
                case 'exponential cooling'
                    app.Coolingequation.Text = 'T=T0*exp(-k*t)';
                    app.time.XLabel.String='Modeled cooling rate';
                    xlabel(app.hs,'Cooling rate')
                    app.TtdistributionsLabel.Text='rate-t distributions';
                    app.errorcontributions.YLabel.String='Uncertainty budget';
                case 'parabolic cooling'
                    app.Coolingequation.Text = 'T=T0-kt^2';
                    app.time.XLabel.String='Modeled cooling rate';
                    xlabel(app.hs,'Cooling rate')
                    app.TtdistributionsLabel.Text='rate-t distributions';
                    app.errorcontributions.YLabel.String='Uncertainty budget';
            end
        end

        % Value changed function: ExamplesDropDown
        function ExamplesDropDownValueChanged(app, event)
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            app.Element.Items=unique(elementlist,'stable');
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            app.Reference.Items=referencelist;
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
        end

        % Value changed function: Element
        function ElementValueChanged(app, event)
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            app.Reference.Items=referencelist;
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
        end

        % Value changed function: Reference
        function ReferenceValueChanged(app, event)
            mineral = app.ExamplesDropDown.Value;
            mineral_site=find(strcmp(app.name(:,1),mineral));
            elementlist=app.name(mineral_site,2);
            element=app.Element.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name(element_site,3);
            reference=app.Reference.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable2.Data=app.DiffCoef(reference_site(1),:);
        end

        % Button pushed function: CalculatetButton
        function CalculatetButtonPushed(app, event)
            global profile_x profile_C profile_eC initial_x initial_C
            ratename=''; t_MC=[]; rate_MC=[]; fc_rate=''; Tfinal=[];
            tol_percent=1e-8; %tolerance in percent when diffusion stops
            f_dt=0.5; %dt=f*(dx)^2/2D
            R = 8.314; %gas constant

            %deconvolution parameters, parfpr cannot invoke struct variable
            deconvolution=app.DeconvoluteanalyticalbeameffectsCheckBox.Value;
            if app.CircularButton.Value
                beamshape='circular';
            elseif app.RectangleButton.Value
                beamshape='rectangle';
            elseif app.GaussButton.Value
                beamshape='gauss';
            elseif app.LorentzButton.Value
                beamshape='lorentz';
            elseif app.VoigtButton.Value
                beamshape='voigt';
            end
            deconv_parameters={};
            deconv_parameters{1,1}='deconvolute'; deconv_parameters{1,2}=deconvolution;
            deconv_parameters{2,1}='beam shape'; deconv_parameters{2,2}=beamshape;
            deconv_parameters{3,1}='beam size unit'; deconv_parameters{3,2}=app.UnitDropDown.Value;
            deconv_parameters{4,1}='diameter'; deconv_parameters{4,2}=app.DiameterEditField.Value;
            deconv_parameters{5,1}='width'; deconv_parameters{5,2}=app.WidthEditField.Value;
            deconv_parameters{6,1}='FWHM'; deconv_parameters{6,2}=app.FWHMEditField.Value;
            deconv_parameters{7,1}='FWHM Gauss'; deconv_parameters{7,2}=app.FWHMGaussEditField.Value;
            deconv_parameters{8,1}='FWHM Lorentz'; deconv_parameters{8,2}=app.FWHMLorentzEditField.Value;

            XAn=[];
            Nx=app.DistancegridEditField.Value; %number of intervals
            
            Lx=max(initial_x)-min(initial_x); %diffusion profile length
            dx=Lx/Nx; %grid size in x
            x_grid=min(initial_x)+(0:Nx)*dx; %x values on the grid
            x_grid=x_grid';
            initial_C_interp = interp1(initial_x,initial_C,x_grid,"linear","extrap");
            u = initial_C_interp;

            cla(app.time)
            cla(app.curvefitting)
            yyaxis(app.curvefitting,"right")
            set(app.curvefitting,'Ycolor','k');
            yticks(app.curvefitting,[])
            cla(app.curvefitting)
            yyaxis(app.curvefitting,"left")
            cla(app.hs)
            cla(app.hx)
            cla(app.hy)
            cla(app.errorcontributions)
            xticklabels(app.errorcontributions,'')

            error_contribution=[1 1 1];
            error_contribution_rate=[1 1 1];
            T = app.InitialtemperatureEditField.Value+273.15; %initial temperature, K
            eT = app.TemperatureerrorEditField.Value; %initial temperature uncertainty, K
            ttrials = app.MonteCarlotrialsEditField.Value;
            withDerror=app.PropagateDerrorCheckBox.Value;

            Dtype=app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Value;

            if isempty(profile_eC)
                Weight=1;
            else
                Weight=1./(profile_eC.^2);
            end

            %cooling rate
            coolingpath = app.CoolingpathDropDown.Value;
            if strcmp(coolingpath,'isothermal')
                iscooling = 0; %cooling rate is 0
            else
                iscooling = 1; %cooling rate is unknown
            end
    
            app.time.XLabel.String='Timescale (second)';%Restore to a common name when click 'show the diffusion process'
            
            %Calculate the best fit===========================
            wb=uiprogressdlg(app.DiffuserGUI,'Title','Searching for the best fit','Indeterminate','on');
            if Dtype %D=f(T)
                diffcoef=app.UITable2.Data;
                if iscooling
                    [rate, t, ~, misfit, rd, x_grid, u_fit, u_fit_conv, isbreak, Ibd]= diffusion_CN_rate(...
                        profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef,tol_percent,deconv_parameters);
                    writecell({'Initial temperature(°C)','Final temperature(°C)','Cooling rate(°C/s)','Cooling time(s)'},'log.txt','Delimiter','tab')  
                else
                    [t, misfit, rd, x_grid, u_fit, u_fit_conv, isbreak, Ibd] = diffusion_CN(...
                        profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,diffcoef,deconv_parameters);
                end
            else %D=f(T,P,fO2,C...)
                diffcoef=app.UITable4.Data;
                if isunix && ~ismac
                    st=load(['/home/sims/Documents/MATLAB/Diffusion/',app.CovName_selected]);
                else
                    st=load(app.CovName_selected);
                end
                covmatrix=st.covmatrix;
                if strcmp(app.fO2EditField.Enable,'on')
                    fO2=app.fO2EditField.Value; %bar
                else
                    fO2=1; %lnfO2=0;
                end
                if strcmp(app.PressureEditField.Enable,'on')
                    P=app.PressureEditField.Value * 1e5; %Pa
                else
                    P=0;
                end
                if strcmp(app.fO2EditField.Enable,'on')
                    aSiO2=app.aSiO2EditField.Value;
                    if aSiO2==0
                        uialert(app.DiffuserGUI,'aSiO2 should be >0','')
                        return
                    end
                else
                    aSiO2=1; %lnaSiO2=0;
                end
                if strcmp(app.XAnDropDown.Enable,'on') %XAn, and Mn-Ni dependent on XFo
                    columnname=app.XAnDropDown.Value;
                    index=regexp(columnname,'\d','match');
                    XAn=app.data(:,str2double(index{1}));
                    XAn(isnan(XAn))=[];
                    XAn_grid = interp1(initial_x,XAn,x_grid,"linear","extrap");
                    if iscooling
                        [rate, t, ~, misfit, rd, x_grid, u_fit, u_fit_conv, isbreak, Ibd]= diffusion_CN_Pl_rate(...
                            profile_x,profile_C,Weight,initial_x,initial_C,XAn,Nx,f_dt,T,coolingpath,diffcoef,tol_percent,fO2,P,aSiO2,deconv_parameters);
                        writecell({'Initial temperature(°C)','Final temperature(°C)','Cooling rate(°C/s)','Cooling time(s)'},'log.txt','Delimiter','tab')
                    else
                        [t, misfit, rd, x_grid, u_fit, u_fit_conv, isbreak, Ibd]= diffusion_CN_Pl(...
                            profile_x,profile_C,Weight,initial_x,initial_C,XAn,Nx,f_dt,T,diffcoef,fO2,P,aSiO2,deconv_parameters);
                    end
                    
                % elseif strcmp(app.ExamplesDropDown_2.Value,"binary Fe-Ni taenite")
                %     if isunknown
                %         [rate, t, ~, misfit, rd, x_grid, u_fit, isbreak, Ibd]= diffusion_CN_FeNi_rate(profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef,tol_percent);
                %         writecell({'Initial temperature(°C)','Final temperature(°C)','Cooling rate(°C/s)','Cooling time(s)'},'log.txt','Delimiter','tab')
                %     else
                %         [t, misfit, rd, x_grid, u_fit, isbreak, Ibd]= diffusion_CN_FeNi(profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,diffcoef);
                %     end

                else
                    if iscooling
                        [rate, t, ~, misfit, rd, x_grid, u_fit, u_fit_conv, isbreak, Ibd]= diffusion_CN_Ol_rate(...
                            profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef,tol_percent,fO2,P,aSiO2,deconv_parameters);
                        writecell({'Initial temperature(°C)','Final temperature(°C)','Cooling rate(°C/s)','Cooling time(s)'},'log.txt','Delimiter','tab')
                    else
                        [t, misfit, rd, x_grid, u_fit, u_fit_conv, isbreak, Ibd]= diffusion_CN_Ol(...
                            profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,diffcoef,fO2,P,aSiO2,deconv_parameters);
                    end
                end
            end
            close(wb)
            % ======================================================
            if isnan(t) | isbreak
                return
            end
            if isnan(Ibd)
                uialert(app.DiffuserGUI,'Cannot find the best fit, maybe T is too low or cooling rate is too fast','Warning')
                return
            end

            %find the error curve---------------------------------
            [~,Imin]=min(misfit);
            if ~app.PropagatecurvefittingerrorCheckBox.Value
                Ibd=[Imin, Imin];
            end
            %find the 'useful' points between initial and final
            % x=[x_grid; flipud(x_grid); x_grid(1)];
            % y=[u_fit(:,1); flipud(u_fit(:,end)); u_fit(1,1)];
            % [in, on]=inpolygon(profile_x, profile_C, x, y);
            % useful=in | on;
            % for i=(Imin-1):-1:1
            %     Ibd(1)=i; %left bound site
            %     tmp=misfit; tmp(1:Imin)=[];
            %     [~,I]=min(abs(tmp-misfit(i))); %right bound site
            %     Ibd(2)=I+Imin;
            %     %how many spots are within the bound
            %     y=[u_fit(:,i); flipud(u_fit(:,Ibd(2))); u_fit(1,i)];
            %     [in, on]=inpolygon(profile_x(useful), profile_C(useful), x, y);
            %     if (sum(in)+sum(on)) >= floor(0.95*sum(useful))
            %         break
            %     end
            % end


            %determine the ln[t] and ln(rate) error----------------------------
            bt_1=t(Ibd(1));
            bt_2=t(Ibd(2));
            elnt_curvefit=(log(bt_2/bt_1))/2; %treat average as 2 sigma
            error_contribution(1)=elnt_curvefit;
            if iscooling
                bt_1=rate(Ibd(1));
                bt_2=rate(Ibd(2));
                elnr_curvefit=(log(bt_1/bt_2))/2; %treat average as 2 sigma
                error_contribution_rate(1)=elnr_curvefit;
            end

            %plot curve fit result----------------------------------------------------------------------
            if ~iscooling
                if isempty(profile_eC)
                    plot(app.curvefitting, profile_x, profile_C, 'LineStyle','none','Marker','o','MarkerEdgeColor',[0.5, 0.5, 0.5]);
                else
                    h=errorbar(app.curvefitting, profile_x, profile_C, profile_eC);
                    h.Marker='.';
                    h.MarkerSize=12;
                    h.LineStyle='none';
                    h.CapSize=0;
                end
                hold(app.curvefitting,'on')
                %deconv curve
                plot(app.curvefitting,x_grid,initial_C_interp,'k-');
                plot(app.curvefitting,x_grid,u_fit(:,Imin),'r-')
                if app.PropagatecurvefittingerrorCheckBox.Value
                    h=plot(app.curvefitting,x_grid,u_fit(:,Ibd),'r--');
                    set(get(get(h(2),'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                end
                %conv curve
                if deconvolution
                    plot(app.curvefitting,x_grid,u_fit_conv(:,Imin),'-', 'Color',[0.1922    0.5098    0.7412])
                    % h=plot(app.curvefitting,x_grid,u_fit_conv(:,Ibd),'--', 'Color',[0.1922    0.5098    0.7412]);
                    % set(get(get(h(2),'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                end

                if isempty(XAn)
                    if deconvolution
                        if app.PropagatecurvefittingerrorCheckBox.Value
                            legend(app.curvefitting,{'measured data','initial condition','best fit (deconvoluted)','95c.l. of best fit','best fit (convoluted)'},'Location','best')
                        else
                            legend(app.curvefitting,{'measured data','initial condition','best fit (deconvoluted)','best fit (convoluted)'},'Location','best')
                        end
                    else
                        if app.PropagatecurvefittingerrorCheckBox.Value
                            legend(app.curvefitting,{'measured data','initial condition','best fit','95c.l. of best fit'},'Location','best')
                        else
                            legend(app.curvefitting,{'measured data','initial condition','best fit'},'Location','best')
                        end
                    end
                else
                    yyaxis(app.curvefitting,"right")
                    plot(app.curvefitting,x_grid,XAn_grid,'LineStyle',"--","Color", [4, 114, 190]/255);
                    app.curvefitting.YColor=[4, 114, 190]/255;
                    ylabel(app.curvefitting,app.XAnDropDownLabel.Text)
                    yticks(app.curvefitting,'auto')
                    yyaxis(app.curvefitting,"left")
                    if deconvolution
                        if app.PropagatecurvefittingerrorCheckBox.Value
                            legend(app.curvefitting,{'measured data','initial condition','best fit (deconvoluted)','95c.l. of best fit','best fit (convoluted)', app.XAnDropDownLabel.Text},'Location','best')
                        else
                            legend(app.curvefitting,{'measured data','initial condition','best fit (deconvoluted)','best fit (convoluted)', app.XAnDropDownLabel.Text},'Location','best')
                        end
                    else
                        if app.PropagatecurvefittingerrorCheckBox.Value
                            legend(app.curvefitting,{'measured data','initial condition','best fit','95c.l. of best fit',app.XAnDropDownLabel.Text},'Location','best')
                        else
                            legend(app.curvefitting,{'measured data','initial condition','best fit',app.XAnDropDownLabel.Text},'Location','best')
                        end
                    end
                end
                hold(app.curvefitting,'off')

            else %cooling rate is not 0----------------------------------------------------------------
                %define rate unit
                if  rate(Imin)>1 %°C/second
                    fc_rate=1;
                    unitname_rate='/second';
                elseif rate(Imin)*60>1 %°C/minute
                    fc_rate=60;
                    unitname_rate='/minute';
                elseif rate(Imin)*3600>1 %°C/hour
                    fc_rate=3600;
                    unitname_rate='/hour';
                elseif rate(Imin)*(24*3600)>1 %°C/day
                    fc_rate=24*3600;
                    unitname_rate='/day';
                elseif rate(Imin)*(365*24*3600)>1 %°C/year
                    fc_rate=365*24*3600;
                    unitname_rate='/year';
                elseif rate(Imin)*(1e3*365*24*3600)>1 %°C/kyr
                    fc_rate=1e3*365*24*3600;
                    unitname_rate='/kyr';
                else %if rate(Imin)*(1e6*365*24*3600)>1 %°C/myr
                    fc_rate=1e6*365*24*3600;
                    unitname_rate='/myr';
                end
                switch coolingpath
                    case 'linear cooling'
                        unitname_rate=['°C',unitname_rate];
                    case 'exponential cooling'
                        unitname_rate=['1',unitname_rate];
                    case 'parabolic cooling'
                         if  mean(rate,'omitnan')>1 %°C/second
                            fc_rate=1;
                            unitname_rate='/second';
                        elseif mean(rate,'omitnan')*(60^2)>1 %°C/minute
                            fc_rate=60^2;
                            unitname_rate='/minute';
                        elseif mean(rate,'omitnan')*(3600^2)>1 %°C/hour
                            fc_rate=3600^2;
                            unitname_rate='/hour';
                        elseif mean(rate,'omitnan')*(24*3600)^2>1 %°C/day
                            fc_rate=(24*3600)^2;
                            unitname_rate='/day';
                        elseif mean(rate,'omitnan')*(365*24*3600)^2>1 %°C/year
                            fc_rate=(365*24*3600)^2;
                            unitname_rate='/year';
                        elseif mean(rate,'omitnan')*(1e3*365*24*3600)^2>1 %°C/kyr
                            fc_rate=(1e3*365*24*3600)^2;
                            unitname_rate='/kyr';
                        else %if mean(rate,'omitnan')*(1e6*365*24*3600)^2>1 %°C/myr
                            fc_rate=(1e6*365*24*3600)^2;
                            unitname_rate='/myr';
                        end
                        unitname_rate=['°C',unitname_rate,'{^2}'];
                end
                ratename=['Cooling rate (',unitname_rate,')'];
                %Plot
                cla(app.curvefitting)
                plot(app.curvefitting,profile_x,profile_C,'LineStyle','none','Marker','o','MarkerEdgeColor',[0.5, 0.5, 0.5]);
                hold(app.curvefitting, "on")
                %deconv curve
                plot(app.curvefitting,x_grid,initial_C_interp,'k-');
                plot(app.curvefitting, x_grid,u_fit(:,Imin),'r-');
                if app.PropagatecurvefittingerrorCheckBox.Value
                    h=plot(app.curvefitting, x_grid,u_fit(:,Ibd),'r--');
                    set(get(get(h(2),'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                end
                %conv curve
                if deconvolution
                    plot(app.curvefitting,x_grid,u_fit_conv(:,Imin),'-', 'Color',[0.1922    0.5098    0.7412])
                    % h=plot(app.curvefitting,x_grid,u_fit_conv(:,Ibd),'--', 'Color',[0.1922    0.5098    0.7412]);
                    % set(get(get(h(2),'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                end
                hold(app.curvefitting, "off")
                if app.PropagatecurvefittingerrorCheckBox.Value
                    legend(app.curvefitting,{'measured data','initial condition'...
                        ['best fit - ', num2str(rate(Imin)*fc_rate,'%.2f'), unitname_rate],...
                        '95c.l. of best fit'})
                else
                    legend(app.curvefitting,{'measured data','initial condition'...
                        ['best fit - ', num2str(rate(Imin)*fc_rate,'%.2f'), unitname_rate]})
                end
                %cooling rate vs. RSS
                plot(app.time, rate*fc_rate, misfit,'k-');
                hold(app.time, "on")
                plot(app.time, rate(Imin)*fc_rate, misfit(Imin),'ro');
                if app.PropagatecurvefittingerrorCheckBox.Value
                    plot(app.time, rate(Ibd(1):Ibd(2))*fc_rate, misfit(Ibd(1):Ibd(2)),'r-');
                end
                hold(app.time, "off")
                n=length(rate);
                if Ibd(2)<n
                    x1=Ibd(2)+1;
                else
                    x1=Ibd(2);
                end
                if Ibd(1)>2
                    x2=Ibd(1)-1;
                else
                    x2=Ibd(1);
                end
                xm=rate([x1, x2])*fc_rate;
                if app.PropagatecurvefittingerrorCheckBox.Value
                    xlim(app.time, xm)
                else
                    xlim(app.time, 'auto')
                end
                xlabel(app.time,ratename)
                if app.PropagatecurvefittingerrorCheckBox.Value
                    legend(app.time, {'','best fit','95c.l. of best fit'})
                else
                    legend(app.time, {'','best fit'})
                end
            end

            %define time unit---------------------------------
            if t(Ibd(2))>(1e6*365*24*3600) %myr
                fc=1e6*365*24*3600;
                tname='Timescale (myr)';
            elseif t(Ibd(2))>(1e3*365*24*3600) %kyr
                fc=1e3*365*24*3600;
                tname='Timescale (kyr)';
            elseif t(Ibd(2))>(365*24*3600) %year
                fc=365*24*3600;
                tname='Timescale (year)';
            elseif t(Ibd(2))>(24*3600) %day
                fc=24*3600;
                tname='Timescale (day)';
            elseif t(Ibd(2))>3600 %hour
                fc=3600;
                tname='Timescale (hour)';
            elseif t(Ibd(2))>60 %minute
                fc=60;
                tname='Timescale (minute)';
            else %second
                fc=1;
                tname='Timescale (second)';
            end
            startindex=regexp(tname, '(');
            endindex=regexp(tname, ')');
            unitname_t=tname(startindex+1:endindex-1);

            %plot misfit against time---------------------------------
            if ~iscooling
                plot(app.time,t/fc,misfit,'k-')
                hold(app.time,'on')
                plot(app.time,t(Imin)/fc,misfit(Imin),'ro')
                if app.PropagatecurvefittingerrorCheckBox.Value
                    plot(app.time,t(Ibd(1):Ibd(2))/fc,misfit(Ibd(1):Ibd(2)),'r-','LineWidth',2)
                end
                if deconvolution
                    if app.PropagatecurvefittingerrorCheckBox.Value
                        legend(app.time,{'','best fit (deconvoluted)','95c.l. of best fit'})
                    else
                        legend(app.time,{'','best fit (deconvoluted)'})
                    end
                else
                    if app.PropagatecurvefittingerrorCheckBox.Value
                        legend(app.time,{'','best fit','95c.l. of best fit'})
                    else
                        legend(app.time,{'','best fit'})
                    end
                end
                hold(app.time,'off')
                xlabel(app.time,tname)
                xlim(app.time,'auto')
            end

            %plot rate-r distribution without T and D error propagation,
            %only with curve fitting error---------------------------------
            if eT==0 && ~withDerror
                %timescale error-------------------------------------------
                bt1=t(Ibd(1));
                bt2=t(Ibd(2));
                [~,I]=min(misfit);
                mt=t(I);
                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                lnt1=normrnd(log(mt),elnt/2,[ttrials,1]); %random select
                t_MC=exp(lnt1); %unit is s
                y=t_MC/fc;
                if ~iscooling %isothermal
                    histogram(app.hy,y,'Orientation','horizontal');
                    xlabel(app.hs,'Temperature (\circC)')
                    ylabel(app.hs,tname)
                    text(app.hy,0,1.1,['Timescale = ',newline,num2str(t(Imin)/fc,'%.1f'),'^{+',num2str(bt_2/fc-t(Imin)/fc,'%.1f'),'}',...
                        '_{-',num2str(t(Imin)/fc-bt_1/fc,'%.1f'),'} ' unitname_t],...
                        'Units','normalized','HorizontalAlignment',"left",'Color','r','FontSize',14)
                else %cooling rate is unknown
                    %cooling rate error------------------------------------
                    bt1=rate(Ibd(1));
                    bt2=rate(Ibd(2));
                    [~,I]=min(misfit);
                    mt=rate(I);
                    elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                    lnt1=normrnd(log(mt),elnt/2,[ttrials,1]); %random select
                    rate_MC=exp(lnt1);

                    %Cooling rate should correspond to each t
                    if Dtype
                        [t_MC, Tfinal] = rate2time(app,rate_MC,coolingpath,u,T,diffcoef,dx,f_dt,tol_percent);
                        % [~, t_MC, ~, Tfinal, ~, ~] = goodrate(rate_MC, coolingpath, ...
                        %     u, T, diffcoef, dx, x_grid, profile_x, profile_C, Weight, inf, f_dt, tol_percent);
                    elseif strcmp(app.XAnDropDown.Enable,'on')
                        [t_MC, Tfinal] = rate2time_Pl(app,rate_MC,coolingpath,u,XAn_grid,T,diffcoef,dx,f_dt,tol_percent,fO2,P,aSiO2);
                        % [~, t_MC, ~, Tfinal, ~, ~] = goodrate_Pl(rate_MC, coolingpath, ...
                        %     u, XAn_grid, T, diffcoef, fO2, P, aSiO2, dx, x_grid, profile_x, profile_C, Weight, inf, f_dt, tol_percent);
                    % elseif strcmp(app.ExamplesDropDown_2.Value,"binary Fe-Ni taenite")
                    %     [t_MC, Tfinal] = rate2time_FeNi(app,rate_MC,coolingpath,u,T,diffcoef,dx,f_dt,tol_percent);
                    %     % [~, t_MC, ~, Tfinal, ~, ~] = goodrate_FeNi(rate_MC, coolingpath, ...
                    %     %     u, T, diffcoef, dx, x_grid, profile_x, profile_C, Weight, inf, f_dt, tol_percent);
                    else
                        [t_MC, Tfinal] = rate2time_Ol(app,rate_MC,coolingpath,u,T,diffcoef,dx,f_dt,tol_percent,fO2,P,aSiO2);
                        % [~, t_MC, ~, Tfinal, ~, ~] = goodrate_Ol(rate_MC, coolingpath, ...
                        %     u, T, diffcoef, fO2, P, aSiO2, dx, x_grid, profile_x, profile_C, Weight, inf, f_dt, tol_percent);
                    end
                    y=t_MC/fc;
                    x=rate_MC*fc_rate; %Unit is the same as figure of app.time
                    x(isnan(y))=[]; y(isnan(y))=[];
                    mlogx=mean(log(x));
                    mlogy=mean(log(y));
                    eb=std(log(y))*2; %2 sigma absolute error
                    ex=std(log(x))*2;
                    outlier1=exp(mlogy+eb*1.5); %filter with 3 sigma
                    outlier2=exp(mlogy-eb*1.5); %filter with 3 sigma
                    x(y>outlier1)=[]; y(y>outlier1)=[];
                    x(y<outlier2)=[]; y(y<outlier2)=[];
                    
                    if app.PropagatecurvefittingerrorCheckBox.Value || app.PropagateDerrorCheckBox.Value || ~isequal(eT,0)
                        xydistribution(app,x,y);
                    end

                    xlabel(app.hs,app.time.XLabel.String) %Unit is the same as figure of app.time
                    ylabel(app.hs,tname)
                    text(app.hy,0,1.1,['Timescale = ',num2str(exp(mlogy),'%.1f'),...
                        '^{+',num2str(exp(mlogy+eb)-exp(mlogy),'%.1f'),'}',...
                        '_{-',num2str(exp(mlogy)-exp(mlogy-eb),'%.1f'),'} ',unitname_t,...
                        newline,'Cooling rate = ',num2str(exp(mlogx),'%.2f'),...
                        '^{+',num2str(exp(mlogx+ex)-exp(mlogx),'%.2f'),'}',...
                        '_{-',num2str(exp(mlogx)-exp(mlogx-ex),'%.2f'),'} ',unitname_rate],...
                        'Units','normalized','HorizontalAlignment',"left",'Color','r','FontSize',12)
                end
            end


            %propagate T uncertainty*************************************************************
            %**********************************************************************
            isbreak=0;
            %1 = D error not considered, 2 = D error considered
            if Dtype %use D = f(T)
                tmp=NaN(ttrials,1);
                lnD0=diffcoef{1}; %D0=exp(lnD0);
                elnD0=diffcoef{2}/2; %1 sigma
                if isnan(elnD0); elnD0=0; end
                E=diffcoef{3}; %kJ/mol
                eE=diffcoef{4}/2;  %1 sigma
                if isnan(eE); eE=0; end
                Cov=diffcoef{5}; %covariance between ln(D0) and E
                if isnan(Cov); Cov=0; end
                % D0n1=ones(ttrials,1)*D0;
                % En1=ones(ttrials,1)*E;
                % Diffcoef_trials1=[D0n1,tmp,En1];
                cov2=[elnD0^2 Cov; Cov eE^2]; %covariance matrix between ln(D) and E
                R2 = mvnrnd([lnD0,E],cov2,ttrials);
                D0n2=exp(R2(:,1)); En2=R2(:,2);
                Diffcoef_trials2=num2cell([log(D0n2),tmp,En2]);
            else %use D = f(T,P,fO2,C...)
                Diffcoef_trials=zeros(ttrials,length(diffcoef));
                not0=(diffcoef~=0);
                tmp_diffcoef=diffcoef;
                tmp_diffcoef(tmp_diffcoef==0)=[];
                tmp=mvnrnd(tmp_diffcoef,covmatrix,ttrials);
                Diffcoef_trials(:,not0)=tmp;
            end

            
            wb=uiprogressdlg(app.DiffuserGUI,'Indeterminate', 'on');
            wb.Title='Monte Carlo estimating time uncertainty';
            if eT>0 %consider temperature error
                %1 = D error not considered, 2 =D error considered
                t_MC1=NaN(ttrials,1);
                t_MC2=NaN(ttrials,1);
                rate_MC1=NaN(ttrials,1);
                rate_MC2=NaN(ttrials,1);
                Tn=normrnd(T,eT/2,ttrials,1);
                Tfinal=Tn;
                %define parameters first if using parallel computing
                I1=Ibd(1); I2=Ibd(2);

                %Cooling rate is 0
                %=======================================================
                if ~iscooling 
                    if Dtype
                        a1=exp(diffcoef{1}); a2=diffcoef{3};
                        parfor j=1:ttrials
                            T=Tn(j);
                            Dm1=a1*exp(-a2*1000/R/T);
                            dt1=(dx)^2/(2*Dm1)*f_dt;
                            %Propagate temperature error
                            %different T-D-cooling path have the same time step at minium misfit
                            %isothermal, dt is constant
                            bt1=dt1*I1;
                            bt2=dt1*I2;
                            mt=dt1*Imin;
                            elnt=log(bt2/bt1)/2; %treat as 2 sigma
                            lnt1=normrnd(log(mt),elnt/2); %random select
                            t_MC1(j)=exp(lnt1);
                            %Propagate D error
                            if withDerror
                                D02=D0n2(j); E2=En2(j);
                                Dm2=D02*exp(-E2*1000/R/T);
                                dt2=(dx)^2/(2*Dm2)*f_dt;
                                %different T-D-cooling path have the same time step at minium misfit
                                %isothermal, dt is constant
                                bt1=dt2*I1;
                                bt2=dt2*I2;
                                mt=dt2*Imin;
                                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                                lnt2=normrnd(log(mt),elnt/2); %random select
                                t_MC2(j)=exp(lnt2);
                            end
                        end

                    % elseif strcmp(app.ExamplesDropDown_2.Value,"binary Fe-Ni taenite")
                    %     a1=diffcoef(1); a2=diffcoef(3); a3=diffcoef(4); a4=diffcoef(6);
                    %     parfor j=1:ttrials
                    %         T=Tn(j);
                    %         D1=exp(a1+a2*u+a3/T+a4*u/T);
                    %         Dm1=max(D1);
                    %         dt1=(dx)^2/(2*Dm1)*f_dt;
                    %         %Propagate temperature error
                    %         %different T-D-cooling path have the same time step at minium misfit
                    %         %isothermal, dt is constant
                    %         bt1=dt1*I1;
                    %         bt2=dt1*I2;
                    %         mt=dt1*Imin;
                    %         elnt=log(bt2/bt1)/2; %treat as 2 sigma
                    %         lnt1=normrnd(log(mt),elnt/2); %random select
                    %         t_MC1(j)=exp(lnt1);
                    %         %Propagate D error
                    %         if withDerror
                    %             diffcoef2=Diffcoef_trials(j,:);
                    %             D2=exp(diffcoef2(1)+diffcoef2(3)*u+diffcoef2(4)/T+diffcoef2(6)*u/T);
                    %             Dm2=max(D2);
                    %             dt2=(dx)^2/(2*Dm2)*f_dt;
                    %             %different T-D-cooling path have the same time step at minium misfit
                    %             %isothermal, dt is constant
                    %             bt1=dt2*I1;
                    %             bt2=dt2*I2;
                    %             mt=dt2*Imin;
                    %             elnt=log(bt2/bt1)/2; %treat as 2 sigma
                    %             lnt2=normrnd(log(mt),elnt/2); %random select
                    %             t_MC2(j)=exp(lnt2);
                    %         end
                    %     end

                    elseif strcmp(app.XAnDropDown.Enable,'on') %XAn, and Mn-Ni dependent on XFo
                        a1=diffcoef(1); a2=diffcoef(2); a3=diffcoef(3); a4=diffcoef(4); a5=diffcoef(5); a6=diffcoef(6); a7=diffcoef(7);
                        parfor j=1:ttrials
                            T=Tn(j);
                            D1=exp(a1+a2*log(fO2)+a3*XAn_grid...
                                +a4/T+a5*P+a6*P/T+a7*log(aSiO2));
                            Dm1=max(D1);
                            dt1=(dx)^2/(2*Dm1)*f_dt;
                            %Propagate temperature error
                            %different T-D-cooling path have the same time step at minium misfit
                            %isothermal, dt is constant
                            bt1=dt1*I1;
                            bt2=dt1*I2;
                            mt=dt1*Imin;
                            elnt=log(bt2/bt1)/2; %treat as 2 sigma
                            lnt1=normrnd(log(mt),elnt/2); %random select
                            t_MC1(j)=exp(lnt1);
                            %Propagate D error
                            if withDerror
                                diffcoef2=Diffcoef_trials(j,:);
                                D2=exp(diffcoef2(1)+diffcoef2(2)*log(fO2)+diffcoef2(3)*XAn_grid...
                                    +diffcoef2(4)/T+diffcoef2(5)*P+diffcoef2(6)*P/T+diffcoef2(7)*log(aSiO2));
                                Dm2=max(D2);
                                dt2=(dx)^2/(2*Dm2)*f_dt;
                                %different T-D-cooling path have the same time step at minium misfit
                                %isothermal, dt is constant
                                bt1=dt2*I1;
                                bt2=dt2*I2;
                                mt=dt2*Imin;
                                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                                lnt2=normrnd(log(mt),elnt/2); %random select
                                t_MC2(j)=exp(lnt2);
                            end
                        end

                    else %Ol+Pl
                        a1=diffcoef(1); a2=diffcoef(2); a3=diffcoef(3); a4=diffcoef(4); a5=diffcoef(5); a6=diffcoef(6); a7=diffcoef(7);
                        parfor j=1:ttrials
                            T=Tn(j);
                            D1=exp(a1+a2*log(fO2)+a3*u+a4/T+a5*P+a6*P/T+a7*log(aSiO2));
                            Dm1=max(D1);
                            dt1=(dx)^2/(2*Dm1)*f_dt;
                            %Propagate temperature error
                            %different T-D-cooling path have the same time step at minium misfit
                            %isothermal, dt is constant
                            bt1=dt1*I1;
                            bt2=dt1*I2;
                            mt=dt1*Imin;
                            elnt=log(bt2/bt1)/2; %treat as 2 sigma
                            lnt1=normrnd(log(mt),elnt/2); %random select
                            t_MC1(j)=exp(lnt1);
                            %Propagate D error
                            if withDerror
                                diffcoef2=Diffcoef_trials(j,:);
                                D2=exp(diffcoef2(1)+diffcoef2(2)*log(fO2)+diffcoef2(3)*u...
                                    +diffcoef2(4)/T+diffcoef2(5)*P+diffcoef2(6)*P/T+diffcoef2(7)*log(aSiO2));
                                Dm2=max(D2);
                                dt2=(dx)^2/(2*Dm2)*f_dt;
                                %different T-D-cooling path have the same time step at minium misfit
                                %isothermal, dt is constant
                                bt1=dt2*I1;
                                bt2=dt2*I2;
                                mt=dt2*Imin;
                                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                                lnt2=normrnd(log(mt),elnt/2); %random select
                                t_MC2(j)=exp(lnt2);
                            end
                        end
                    end
                end


                %Cooling rate is unknown
                %======================================================
                if iscooling
                    %1 = D error not considered, 2 =D error considered
                    if Dtype
                        parfor j=1:ttrials
                            %Propagate temperature error
                            T=Tn(j);
                            [rate1i, t1i, T1i, misfit1i,~,~,~,~,~,Ibd1i]= diffusion_CN_rate(...
                                profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef,tol_percent,deconv_parameters);
                            %cooling rate error
                            bt1=rate1i(Ibd1i(1));
                            bt2=rate1i(Ibd1i(2));
                            [~,I]=min(misfit1i);
                            mt=rate1i(I);
                            elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                            lnt1=normrnd(log(mt),elnt/2); %random select
                            [~,I]=min(abs(rate1i-exp(lnt1))); %most close to the selected one
                            rate_MC1(j)=rate1i(I);
                            t_MC1(j)=t1i(I); %Cooling rate should correspond to each t
                            Tfinal(j)=T1i(I);
                            %Propagate D error
                            if withDerror
                                diffcoef2=Diffcoef_trials2(j,:);
                                [rate2i, t2i, T2i, misfit2i,~,~,~,~,~,Ibd2i]= diffusion_CN_rate(...
                                    profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent,deconv_parameters);
                                %cooling rate error
                                bt1=rate2i(Ibd2i(1));
                                bt2=rate2i(Ibd2i(2));
                                [~,I]=min(misfit2i);
                                mt=rate2i(I);
                                elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                                lnt2=normrnd(log(mt),elnt/2); %random select
                                [~,I]=min(abs(rate2i-exp(lnt2))); %most close to the selected one
                                rate_MC2(j)=rate2i(I);
                                t_MC2(j)=t2i(I); %Cooling rate should correspond to each t
                                Tfinal(j)=T2i(I);
                                writematrix([T-273.15,T2i(I)-273.15,rate_MC2(j),t_MC2(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            else
                                writematrix([T-273.15,T1i(I)-273.15,rate_MC1(j),t_MC1(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            end
                        end

                    % elseif strcmp(app.ExamplesDropDown_2.Value,"binary Fe-Ni taenite")
                    %     parfor j=1:ttrials
                    %         %Propagate temperature error
                    %         T=Tn(j);
                    %         [rate1i, t1i, T1i, misfit1i,~,~,~,~,Ibd1i] = diffusion_CN_FeNi_rate(...
                    %             profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef,tol_percent);
                    %         %cooling rate error
                    %         bt1=rate1i(Ibd1i(1));
                    %         bt2=rate1i(Ibd1i(2));
                    %         [~,I]=min(misfit1i);
                    %         mt=rate1i(I);
                    %         elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                    %         lnt1=normrnd(log(mt),elnt/2); %random select
                    %         [~,I]=min(abs(rate1i-exp(lnt1))); %most close to the selected one
                    %         rate_MC1(j)=rate1i(I);
                    %         t_MC1(j)=t1i(I); %Cooling rate should correspond to each t
                    %         Tfinal(j)=T1i(I);
                    %         %Propagate D error
                    %         if withDerror
                    %             diffcoef2=Diffcoef_trials(j,:);
                    %             [rate2i, t2i, T2i, misfit2i,~,~,~,~,Ibd2i]= diffusion_CN_FeNi_rate(...
                    %                 profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent);
                    %             %cooling rate error
                    %             bt1=rate2i(Ibd2i(1));
                    %             bt2=rate2i(Ibd2i(2));
                    %             [~,I]=min(misfit2i);
                    %             mt=rate2i(I);
                    %             elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                    %             lnt2=normrnd(log(mt),elnt/2); %random select
                    %             [~,I]=min(abs(rate2i-exp(lnt2))); %most close to the selected one
                    %             rate_MC2(j)=rate2i(I);
                    %             t_MC2(j)=t2i(I); %Cooling rate should correspond to each t
                    %             Tfinal(j)=T2i(I);
                    %             writematrix([T-273.15,T2i(I)-273.15,rate_MC2(j),t_MC2(j)],'log.txt','Delimiter','tab','WriteMode','append')
                    %         else
                    %             writematrix([T-273.15,T1i(I)-273.15,rate_MC1(j),t_MC1(j)],'log.txt','Delimiter','tab','WriteMode','append')
                    %         end
                    %     end
                    
                    elseif strcmp(app.XAnDropDown.Enable,'on') %XAn, and Mn-Ni dependent on XFo
                        parfor j=1:ttrials
                            %Propagate temperature error
                            T=Tn(j);
                            [rate1i, t1i, T1i, misfit1i,~,~,~,~,~,Ibd1i] = diffusion_CN_Pl_rate(...
                                profile_x,profile_C,Weight,initial_x,initial_C,XAn,Nx,f_dt,T,coolingpath,diffcoef,tol_percent,fO2,P,aSiO2,deconv_parameters);
                            %cooling rate error
                            bt1=rate1i(Ibd1i(1));
                            bt2=rate1i(Ibd1i(2));
                            [~,I]=min(misfit1i);
                            mt=rate1i(I);
                            elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                            lnt1=normrnd(log(mt),elnt/2); %random select
                            [~,I]=min(abs(rate1i-exp(lnt1))); %most close to the selected one
                            rate_MC1(j)=rate1i(I);
                            t_MC1(j)=t1i(I); %Cooling rate should correspond to each t
                            Tfinal(j)=T1i(I);
                            %Propagate D error
                            if withDerror
                                diffcoef2=Diffcoef_trials(j,:);
                                [rate2i, t2i, T2i, misfit2i,~,~,~,~,~,Ibd2i]= diffusion_CN_Pl_rate(...
                                    profile_x,profile_C,Weight,initial_x,initial_C,XAn,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent,fO2,P,aSiO2,deconv_parameters);
                                %cooling rate error
                                bt1=rate2i(Ibd2i(1));
                                bt2=rate2i(Ibd2i(2));
                                [~,I]=min(misfit2i);
                                mt=rate2i(I);
                                elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                                lnt2=normrnd(log(mt),elnt/2); %random select
                                [~,I]=min(abs(rate2i-exp(lnt2))); %most close to the selected one
                                rate_MC2(j)=rate2i(I);
                                t_MC2(j)=t2i(I); %Cooling rate should correspond to each t
                                Tfinal(j)=T2i(I);
                                writematrix([T-273.15,T2i(I)-273.15,rate_MC2(j),t_MC2(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            else
                                writematrix([T-273.15,T1i(I)-273.15,rate_MC1(j),t_MC1(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            end
                        end

                    else %Ol+Pl
                        parfor j=1:ttrials
                            %Propagate temperature error
                            T=Tn(j);
                            [rate1i, t1i, T1i, misfit1i,~,~,~,~,~,Ibd1i] = diffusion_CN_Ol_rate(...
                                profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef,tol_percent,fO2,P,aSiO2,deconv_parameters);
                            %cooling rate error
                            bt1=rate1i(Ibd1i(1));
                            bt2=rate1i(Ibd1i(2));
                            [~,I]=min(misfit1i);
                            mt=rate1i(I);
                            elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                            lnt1=normrnd(log(mt),elnt/2); %random select
                            [~,I]=min(abs(rate1i-exp(lnt1))); %most close to the selected one
                            rate_MC1(j)=rate1i(I);
                            t_MC1(j)=t1i(I); %Cooling rate should correspond to each t
                            Tfinal(j)=T1i(I);
                            %Propagate D error
                            if withDerror
                                diffcoef2=Diffcoef_trials(j,:);
                                [rate2i, t2i, T2i, misfit2i,~,~,~,~,~,Ibd2i]= diffusion_CN_Ol_rate(...
                                    profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent,fO2,P,aSiO2,deconv_parameters);
                                %cooling rate error
                                bt1=rate2i(Ibd2i(1));
                                bt2=rate2i(Ibd2i(2));
                                [~,I]=min(misfit2i);
                                mt=rate2i(I);
                                elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                                lnt2=normrnd(log(mt),elnt/2); %random select
                                [~,I]=min(abs(rate2i-exp(lnt2))); %most close to the selected one
                                rate_MC2(j)=rate2i(I);
                                t_MC2(j)=t2i(I); %Cooling rate should correspond to each t
                                Tfinal(j)=T2i(I);
                                writematrix([T-273.15,T2i(I)-273.15,rate_MC2(j),t_MC2(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            else
                                writematrix([T-273.15,T1i(I)-273.15,rate_MC1(j),t_MC1(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            end
                        end

                    end
                    
                end
                %======================================================
                %****************************************************************************************

                if isbreak
                    return
                end

                error_contribution(2)=std(log(t_MC1))*2;
                if iscooling
                    error_contribution_rate(2)=std(log(rate_MC1))*2;
                end
                if withDerror
                    error_contribution(3)=std(log(t_MC2))*2;
                    t_MC=t_MC2;
                    if iscooling
                        rate_MC=rate_MC2;
                        error_contribution_rate(3)=std(log(rate_MC2))*2;
                    end
                else
                    error_contribution(3)=error_contribution(2);
                    t_MC=t_MC1;
                    if iscooling
                        rate_MC=rate_MC1;
                        error_contribution_rate(3)=error_contribution_rate(2);
                    end
                end

                %plot rate-t distributions
                if iscooling
                    x=rate_MC*fc_rate; %Unit is the same as figure of app.time
                else
                    x=Tn-273.15; %℃
                end

                y=t_MC/fc;
                x(isnan(y))=[]; y(isnan(y))=[];
                mlogx=mean(log(x));
                mlogy=mean(log(y));
                eb=std(log(y))*2; %2 sigma absolute error
                ex=std(log(x))*2;

                outlier1=exp(mlogy+eb*1.5); %filter with 3 sigma
                outlier2=exp(mlogy-eb*1.5); %filter with 3 sigma
                x(y>outlier1)=[]; y(y>outlier1)=[];
                x(y<outlier2)=[]; y(y<outlier2)=[];

                xydistribution(app,x,y);

                ylabel(app.hs,tname)
                if iscooling
                    xlabel(app.hs,app.time.XLabel.String) %Unit is the same as figure of app.time
                    text(app.hy,0,1.1,['Timescale = ',num2str(exp(mlogy),'%.1f'),...
                        '^{+',num2str(exp(mlogy+eb)-exp(mlogy),'%.1f'),'}',...
                        '_{-',num2str(exp(mlogy)-exp(mlogy-eb),'%.1f'),'} ',unitname_t,...
                        newline,'Cooling rate = ',num2str(exp(mlogx),'%.2f'),...
                        '^{+',num2str(exp(mlogx+ex)-exp(mlogx),'%.2f'),'}',...
                        '_{-',num2str(exp(mlogx)-exp(mlogx-ex),'%.2f'),'} ',unitname_rate],...
                        'Units','normalized','HorizontalAlignment',"left",'Color','r','FontSize',12)
                else
                    xlabel(app.hs,'Temperature (\circC)')
                    text(app.hy,0,1.1,['Timescale = ',newline,num2str(exp(mlogy),'%.1f'),'^{+',num2str(exp(mlogy+eb)-exp(mlogy),'%.1f'),'}',...
                        '_{-',num2str(exp(mlogy)-exp(mlogy-eb),'%.1f'),'} ',unitname_t],...
                        'Units','normalized','HorizontalAlignment',"left",'Color','r','FontSize',14)
                end

            %eT=0, do not consider temperature error===========================
            else
                Tn=ones(ttrials,1)*T;
                error_contribution(2)=elnt_curvefit;
                if iscooling
                    error_contribution_rate(2)=elnr_curvefit;
                end

                if withDerror
                    t_MC=NaN(ttrials,1);
                    rate_MC=NaN(ttrials,1);

                    %Cooling rate is 0========================================
                    if ~iscooling
                        if Dtype
                            parfor j=1:ttrials
                                D0=D0n2(j); E=En2(j);
                                Dm=D0*exp(-E*1000/R/T);
                                dt=(dx)^2/(2*Dm)*f_dt; %grid size of time
                                %different T-D-cooling path have the same time step at minium misfit
                                %isothermal, dt is constant
                                bt1=dt*Ibd(1);
                                bt2=dt*Ibd(2);
                                mt=dt*Imin;
                                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                                lnt=normrnd(log(mt),elnt/2); %random select
                                t_MC(j)=exp(lnt);
                            end
                        % elseif strcmp(app.ExamplesDropDown_2.Value,"binary Fe-Ni taenite")
                        %     parfor j=1:ttrials
                        %         diffcoef2=Diffcoef_trials(j,:);
                        %         D=exp(diffcoef2(1)+diffcoef2(3)*u+diffcoef2(4)/T+diffcoef2(6)*u/T);
                        %         Dm=max(D);
                        %         dt=(dx)^2/(2*Dm)*f_dt; %grid size of time
                        %         %different T-D-cooling path have the same time step at minium misfit
                        %         %isothermal, dt is constant
                        %         bt1=dt*Ibd(1);
                        %         bt2=dt*Ibd(2);
                        %         mt=dt*Imin;
                        %         elnt=log(bt2/bt1)/2; %treat as 2 sigma
                        %         lnt=normrnd(log(mt),elnt/2); %random select
                        %         t_MC(j)=exp(lnt);
                        %     end
                        elseif strcmp(app.XAnDropDown.Enable,'on') %XAn, and Mn-Ni dependent on XFo
                            parfor j=1:ttrials
                                diffcoef2=Diffcoef_trials(j,:);
                                D=exp(diffcoef2(1)+diffcoef2(2)*log(fO2)+diffcoef2(3)*XAn_grid...
                                    +diffcoef2(4)/T+diffcoef2(5)*P+diffcoef2(6)*P/T+diffcoef2(7)*log(aSiO2));
                                Dm=max(D);
                                dt=(dx)^2/(2*Dm)*f_dt; %grid size of time
                                %different T-D-cooling path have the same time step at minium misfit
                                %isothermal, dt is constant
                                bt1=dt*Ibd(1);
                                bt2=dt*Ibd(2);
                                mt=dt*Imin;
                                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                                lnt=normrnd(log(mt),elnt/2); %random select
                                t_MC(j)=exp(lnt);
                            end
                        else 
                            parfor j=1:ttrials
                                diffcoef2=Diffcoef_trials(j,:);
                                D=exp(diffcoef2(1)+diffcoef2(2)*log(fO2)+diffcoef2(3)*u...
                                    +diffcoef2(4)/T+diffcoef2(5)*P+diffcoef2(6)*P/T+diffcoef2(7)*log(aSiO2));
                                Dm=max(D);
                                dt=(dx)^2/(2*Dm)*f_dt; %grid size of time
                                %different T-D-cooling path have the same time step at minium misfit
                                %isothermal, dt is constant
                                bt1=dt*Ibd(1);
                                bt2=dt*Ibd(2);
                                mt=dt*Imin;
                                elnt=log(bt2/bt1)/2; %treat as 2 sigma
                                lnt=normrnd(log(mt),elnt/2); %random select
                                t_MC(j)=exp(lnt);
                            end
                        end

                        y=t_MC/fc;
                        mlogy=mean(log(y));
                        eb=std(log(y))*2; %2 sigma absolute error

                        histogram(app.hy,y,'Orientation','horizontal');
                        ylabel(app.hs,tname)
                        text(app.hy,0,1.1,['Timescale = ',newline,num2str(exp(mlogy),'%.1f'),'^{+',num2str(exp(mlogy+eb)-exp(mlogy),'%.1f'),'}',...
                            '_{-',num2str(exp(mlogy)-exp(mlogy-eb),'%.1f'),'} ',unitname_t],...
                            'Units','normalized','HorizontalAlignment',"left",'Color','r','FontSize',14)
                    end

                    %Cooling rate is unknown=================================
                    if iscooling
                        if Dtype
                            parfor j=1:ttrials
                                diffcoef2=Diffcoef_trials2(j,:);
                                [ratei, ti, Ti, misfiti,~,~,~,~,~,Ibdi]= diffusion_CN_rate(...
                                    profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent,deconv_parameters);
                                %cooling rate error---------------------------
                                bt1=ratei(Ibdi(1));
                                bt2=ratei(Ibdi(2));
                                [~,I]=min(misfiti);
                                mt=ratei(I);
                                elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                                lnt=normrnd(log(mt),elnt/2); %random select
                                [~,I]=min(abs(ratei-exp(lnt))); %most close to the selected one
                                rate_MC(j)=ratei(I);
                                t_MC(j)=ti(I); %Cooling rate should correspond to each t
                                Tfinal(j)=Ti(I);
                                writematrix([T-273.15,Ti(I)-273.15,rate_MC(j),t_MC(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            end
                        % elseif strcmp(app.ExamplesDropDown_2.Value,"binary Fe-Ni taenite")
                        %     parfor j=1:ttrials
                        %         diffcoef2=Diffcoef_trials(j,:);
                        %         [ratei, ti, Ti, misfiti,~,~,~,~,Ibdi]= diffusion_CN_FeNi_rate(profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent);
                        %         %cooling rate error---------------------------
                        %         bt1=ratei(Ibdi(1));
                        %         bt2=ratei(Ibdi(2));
                        %         [~,I]=min(misfiti);
                        %         mt=ratei(I);
                        %         elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                        %         lnt=normrnd(log(mt),elnt/2); %random select
                        %         [~,I]=min(abs(ratei-exp(lnt))); %most close to the selected one
                        %         rate_MC(j)=ratei(I);
                        %         t_MC(j)=ti(I); %Cooling rate should correspond to each t
                        %         Tfinal(j)=Ti(I);
                        %         writematrix([T-273.15,Ti(I)-273.15,rate_MC(j),t_MC(j)],'log.txt','Delimiter','tab','WriteMode','append')
                        %     end
                        elseif strcmp(app.XAnDropDown.Enable,'on') %XAn, and Mn-Ni dependent on XFo
                            parfor j=1:ttrials
                                diffcoef2=Diffcoef_trials(j,:);
                                [ratei, ti, Ti, misfiti,~,~,~,~,~,Ibdi]= diffusion_CN_Pl_rate(...
                                    profile_x,profile_C,Weight,initial_x,initial_C,XAn,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent,fO2,P,aSiO2,deconv_parameters);
                                %cooling rate error---------------------------
                                bt1=ratei(Ibdi(1));
                                bt2=ratei(Ibdi(2));
                                [~,I]=min(misfiti);
                                mt=ratei(I);
                                elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                                lnt=normrnd(log(mt),elnt/2); %random select
                                [~,I]=min(abs(ratei-exp(lnt))); %most close to the selected one
                                rate_MC(j)=ratei(I);
                                t_MC(j)=ti(I); %Cooling rate should correspond to each t
                                Tfinal(j)=Ti(I);
                                writematrix([T-273.15,Ti(I)-273.15,rate_MC(j),t_MC(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            end
                        else
                            parfor j=1:ttrials
                                diffcoef2=Diffcoef_trials(j,:);
                                [ratei, ti, Ti, misfiti,~,~,~,~,~,Ibdi]= diffusion_CN_Ol_rate(...
                                    profile_x,profile_C,Weight,initial_x,initial_C,Nx,f_dt,T,coolingpath,diffcoef2,tol_percent,fO2,P,aSiO2,deconv_parameters);
                                %cooling rate error---------------------------
                                bt1=ratei(Ibdi(1));
                                bt2=ratei(Ibdi(2));
                                [~,I]=min(misfiti);
                                mt=ratei(I);
                                elnt=log(bt1/bt2)/2; %treat as 2 sigma, bt1>bt2 because misfit move from high rate to low rate
                                lnt=normrnd(log(mt),elnt/2); %random select
                                [~,I]=min(abs(ratei-exp(lnt))); %most close to the selected one
                                rate_MC(j)=ratei(I);
                                t_MC(j)=ti(I); %Cooling rate should correspond to each t
                                Tfinal(j)=Ti(I);
                                writematrix([T-273.15,Ti(I)-273.15,rate_MC(j),t_MC(j)],'log.txt','Delimiter','tab','WriteMode','append')
                            end
                        end

                        x=rate_MC*fc_rate; %Unit is the same as figure of app.time
                        y=t_MC/fc;
                        x(isnan(y))=[]; y(isnan(y))=[];
                        mlogx=mean(log(x));
                        mlogy=mean(log(y));
                        eb=std(log(y))*2; %2 sigma absolute error
                        ex=std(log(x))*2;

                        outlier1=exp(mlogy+eb*1.5); %filter with 3 sigma
                        outlier2=exp(mlogy-eb*1.5); %filter with 3 sigma
                        x(y>outlier1)=[]; y(y>outlier1)=[];
                        x(y<outlier2)=[]; y(y<outlier2)=[];

                        xydistribution(app,x,y);

                        xlabel(app.hs,app.time.XLabel.String) %Unit is the same as figure of app.time
                        ylabel(app.hs,tname)
                        text(app.hy,0,1.1,['Timescale = ',num2str(exp(mlogy),'%.1f'),...
                            '^{+',num2str(exp(mlogy+eb)-exp(mlogy),'%.1f'),'}',...
                            '_{-',num2str(exp(mlogy)-exp(mlogy-eb),'%.1f'),'} ',unitname_t,...
                            newline,'Cooling rate = ',num2str(exp(mlogx),'%.1f'),...
                            '^{+',num2str(exp(mlogx+ex)-exp(mlogx),'%.1f'),'}',...
                            '_{-',num2str(exp(mlogx)-exp(mlogx-ex),'%.1f'),'} ',unitname_rate],...
                            'Units','normalized','HorizontalAlignment',"left",'Color','r','FontSize',12)
                    end

                    if isbreak
                        return
                    end

                    error_contribution(3)=std(log(t_MC))*2;
                    if iscooling
                        error_contribution_rate(3)=std(log(rate_MC))*2;
                    end
                else
                    error_contribution(3)=error_contribution(2);
                    if iscooling
                        error_contribution_rate(3)=error_contribution_rate(2);
                    end
                end

            end
            close(wb);

            %uncertainty budget
            a=error_contribution(1); b=error_contribution(2); c=error_contribution(3);
            if a>b || b>c
                uialert(app.DiffuserGUI,'Trials are not enough to estimate ln[t] uncertainty budget','')
            end
            %ln[t] error
            error_contribution(2)=b-a; error_contribution(3)=c-b;
            tol=sum(error_contribution);
            error_contribution=error_contribution/tol*100;
            %ln[rate] error
            if iscooling
                a2=error_contribution_rate(1); b2=error_contribution_rate(2); c2=error_contribution_rate(3);
                if a2>b2 || b2>c2
                    uialert(app.DiffuserGUI,'Trials are not enough to estimate ln[rate] uncertainty budget','')
                end
                error_contribution_rate(2)=b2-a2; error_contribution_rate(3)=c2-b2;
                tol=sum(error_contribution_rate);
                error_contribution_rate=error_contribution_rate/tol*100;
                bar(app.errorcontributions,[1,2,3],[error_contribution;error_contribution_rate])
                legend(app.errorcontributions,{'ln[t]','ln[rate]'})
            else
                bar(app.errorcontributions,error_contribution,'BarWidth',0.3)
                text(app.errorcontributions,0.98,0.85,['Curve fitting = ', num2str(error_contribution(1), '%.1f'), '%', newline, ...
                    'Temperature = ', num2str(error_contribution(2), '%.1f'), '%', newline,...
                    'Diffusivity = ', num2str(error_contribution(3), '%.1f'), '%'],...
                    'Units','normalized','HorizontalAlignment',"right")
                legend(app.errorcontributions,'off')
            end
            xticklabels(app.errorcontributions,{'Curve fitting','Temperature','Diffusivity'})
            ylim(app.errorcontributions,[0,100])
            
            writecell({'Uncertainty budget of curve fitting','Temperature','Diffusivity','1st line for timesale, 2nd line for cooling rate'},'log.txt','Delimiter','tab','WriteMode','append')
            writematrix([error_contribution;error_contribution_rate],'log.txt','Delimiter','tab','WriteMode','append')
            
            %data to be saved
            app.data2save.tname=tname;
            app.data2save.ratename=ratename;
            app.data2save.Tn=Tn;
            app.data2save.Tfinal=Tfinal;
            app.data2save.t_MC=t_MC;
            app.data2save.rate_MC=rate_MC;
            app.data2save.fc=fc;
            app.data2save.fc_rate=fc_rate;
            app.data2save.coolingpath=coolingpath;
            
            poolobj = gcp('nocreate');
            if isempty(poolobj)
                app.OffMenu.Checked="on";
                app.OnMenu.Checked="off";
                app.ispar=0;
            else
                app.OnMenu.Checked='on';
                app.OffMenu.Checked="off";
                app.ispar=1;
            end
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            if isempty(app.data2save)
                return
            end
            tname=app.data2save.tname;
            ratename=app.data2save.ratename;
            Tn=app.data2save.Tn;
            Tfinal=app.data2save.Tfinal;
            t_MC=app.data2save.t_MC;
            rate_MC=app.data2save.rate_MC;
            fc=app.data2save.fc;
            fc_rate=app.data2save.fc_rate;
            coolingpath=app.data2save.coolingpath;

            if strcmp(coolingpath,'isothermal')
                Title={'Temperature (°C)', tname};
                outputdata=num2cell([Tn-273.15, t_MC/fc]);
            else %include cooling rate
                 Title={'Initial temperature(°C)','Final temperature(°C)',ratename,tname};
                 outputdata=num2cell([Tn-273.15, Tfinal-273.15, rate_MC*fc_rate, t_MC/fc]);
            end

            filename=['Monte Carlo result ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM')];
            [FileName,PathName,~] = uiputfile({'*.txt';'*.csv';'*.xls';'*.xlsx'},'Save result',filename);
            if ~FileName
                return
            end
            [~,~,extension]=fileparts(FileName);
            switch extension
                case '.txt'
                    writecell([Title;outputdata],fullfile(PathName,FileName),"Delimiter",'tab');
                case '.csv'
                    writecell([Title;outputdata],fullfile(PathName,FileName));
                case {'.xlsx','.xls'}
                    %writecell([Title;outputdata],fullfile(PathName,FileName),'AutoWidth',0);
                    writecell(Title,fullfile(PathName,FileName));
                    writecell(outputdata,fullfile(PathName,FileName),'Range','A2');
                otherwise
                    return
            end
        end

        % Button pushed function: SavefiguresButton
        function SavefiguresButtonPushed(app, event)
            filetype={'*.pdf'};
            filename=['Figure ',datestr(now,'yyyy-mm-dd HH-MM-SS.FFF AM')];
            [FileName,PathName] = uiputfile(filetype,'Save figure',filename);
            if ~FileName
                return
            end
            exportgraphics(app.DiffuserGUI,fullfile(PathName,FileName))
        end

        % Button pushed function: SeemanualButton
        function SeemanualButtonPushed(app, event)
            if isunix && ~ismac
                web('/home/sims/Documents/MATLAB/Diffusion/Diffuser_Numerical_Manual.pdf')
            end
        end

        % Value changed function: 
        % UseasimpleArrheniusequationDD0expEaRTCheckBox
        function UseasimpleArrheniusequationDD0expEaRTCheckBoxValueChanged(app, event)
            value = app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Value;
            if value
                app.DisafunctionofTPfO2andcompositionCheckBox.Value=0;
                app.Panel_1.Enable='on';
                app.Panel_2.Enable='off';
            else
                app.DisafunctionofTPfO2andcompositionCheckBox.Value=1;
                app.Panel_1.Enable='off';
                app.Panel_2.Enable='on';
            end
        end

        % Value changed function: DisafunctionofTPfO2andcompositionCheckBox
        function DisafunctionofTPfO2andcompositionCheckBoxValueChanged(app, event)
            value = app.DisafunctionofTPfO2andcompositionCheckBox.Value;
            if value
                app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Value=0;
                app.Panel_1.Enable='off';
                app.Panel_2.Enable='on';
            else
                app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Value=1;
                app.Panel_1.Enable='on';
                app.Panel_2.Enable='off';
            end
        end

        % Value changed function: ExamplesDropDown_2
        function ExamplesDropDown_2ValueChanged(app, event)
            mineral = app.ExamplesDropDown_2.Value;
            mineral_site=find(strcmp(app.name2(:,1),mineral));
            elementlist=app.name2(mineral_site,2);
            app.Element_2.Items=unique(elementlist,'stable');
            element=app.Element_2.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name2(element_site,3);
            app.Reference_2.Items=referencelist;
            reference=app.Reference_2.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable4.Data=app.DiffCoef2(reference_site(1),:);
            app.Dequation.Text=app.Eqlabel(reference_site(1));
            app.CovName_selected=app.CovName{reference_site(1)};

            %check if fO2 is needed
            if app.DiffCoef2(reference_site(1),2)~=0
                app.fO2EditField.Enable='on';
            else
                app.fO2EditField.Enable='off';
            end
            %check if pressure is needed
            if (app.DiffCoef2(reference_site(1),5)~=0 || app.DiffCoef2(reference_site(1),6)~=0)
                app.PressureEditField.Enable='on';
            else
                app.PressureEditField.Enable='off';
            end
            %check if aSiO2 is needed
            if app.DiffCoef2(reference_site(1),7)~=0
                app.aSiO2EditField.Enable='on';
            else
                app.aSiO2EditField.Enable='off';
            end
            %check if XAn is needed, Mn or Ni in olivine and plagioclase
            if app.DiffCoef2(reference_site(1),3)~=0 && ~contains(element, 'Fe-Mg')
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDown.Enable='off';
            end
            %check if element is Mn or Ni in olivine
            if ismember(reference_site(1),[7:12,19:24])
                app.XAnDropDownLabel.Text='XFo';
                app.inplagioclaseLabel.Text='in olivine';
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDownLabel.Text='XAn';
                app.inplagioclaseLabel.Text='in plagioclase';
            end
        end

        % Value changed function: Element_2
        function Element_2ValueChanged(app, event)
            mineral = app.ExamplesDropDown_2.Value;
            mineral_site=find(strcmp(app.name2(:,1),mineral));
            elementlist=app.name2(mineral_site,2);
            element=app.Element_2.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name2(element_site,3);
            app.Reference_2.Items=referencelist;
            reference=app.Reference_2.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable4.Data=app.DiffCoef2(reference_site(1),:);
            app.Dequation.Text=app.Eqlabel(reference_site(1));
            app.CovName_selected=app.CovName{reference_site(1)};

            %check if fO2 is needed
            if app.DiffCoef2(reference_site(1),2)~=0
                app.fO2EditField.Enable='on';
            else
                app.fO2EditField.Enable='off';
            end
            %check if pressure is needed
            if app.DiffCoef2(reference_site(1),5)~=0 || app.DiffCoef2(reference_site(1),6)~=0
                app.PressureEditField.Enable='on';
            else
                app.PressureEditField.Enable='off';
            end
            %check if aSiO2 is needed
            if app.DiffCoef2(reference_site(1),7)~=0
                app.aSiO2EditField.Enable='on';
            else
                app.aSiO2EditField.Enable='off';
            end
            %check if XAn is needed, Mn or Ni in olivine and plagioclase
            if app.DiffCoef2(reference_site(1),3)~=0 && ~contains(element, 'Fe-Mg')
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDown.Enable='off';
            end
            %check if element is Mn or Ni in olivine
            if ismember(reference_site(1),[7:12,19:24])
                app.XAnDropDownLabel.Text='XFo';
                app.inplagioclaseLabel.Text='in olivine';
                app.XAnDropDown.Enable='on'; 
            else
                app.XAnDropDownLabel.Text='XAn';
                app.inplagioclaseLabel.Text='in plagioclase';
            end
        end

        % Value changed function: Reference_2
        function Reference_2ValueChanged(app, event)
            mineral = app.ExamplesDropDown_2.Value;
            mineral_site=find(strcmp(app.name2(:,1),mineral));
            elementlist=app.name2(mineral_site,2);
            element=app.Element_2.Value;
            element_site=mineral_site(strcmp(elementlist,element));
            referencelist=app.name2(element_site,3);
            reference=app.Reference_2.Value;
            reference_site=element_site(strcmp(referencelist,reference));

            app.UITable4.Data=app.DiffCoef2(reference_site(1),:);
            app.Dequation.Text=app.Eqlabel(reference_site(1));
            app.CovName_selected=app.CovName{reference_site(1)};

            %check if fO2 is needed
            if app.DiffCoef2(reference_site(1),2)~=0
                app.fO2EditField.Enable='on';
            else
                app.fO2EditField.Enable='off';
            end
            %check if pressure is needed
            if app.DiffCoef2(reference_site(1),5)~=0 || app.DiffCoef2(reference_site(1),6)~=0
                app.PressureEditField.Enable='on';
            else
                app.PressureEditField.Enable='off';
            end
            %check if aSiO2 is needed
            if app.DiffCoef2(reference_site(1),7)~=0
                app.aSiO2EditField.Enable='on';
            else
                app.aSiO2EditField.Enable='off';
            end
            %check if XAn is needed, Mn or Ni in olivine and plagioclase
            if app.DiffCoef2(reference_site(1),3)~=0 && ~contains(element, 'Fe-Mg')
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDown.Enable='off';
            end
            %check if element is Mn or Ni in olivine
            if ismember(reference_site(1),[7:12,19:24])
                app.XAnDropDownLabel.Text='XFo';
                app.inplagioclaseLabel.Text='in olivine';
                app.XAnDropDown.Enable='on';
            else
                app.XAnDropDownLabel.Text='XAn';
                app.inplagioclaseLabel.Text='in plagioclase';
            end
        end

        % Selection changed function: BeamshapeButtonGroup
        function BeamshapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.BeamshapeButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Circular'
                    app.DiameterEditField.Enable='on';
                    app.DiameterEditFieldLabel.Enable='on';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case 'Rectangle'
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='on';
                    app.WidthEditFieldLabel.Enable='on';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case {'Gauss','Lorentz'}
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='on';
                    app.FWHMEditFieldLabel.Enable='on';
                    app.FWHMGaussEditField.Enable='off';
                    app.FWHMGaussEditFieldLabel.Enable='off';
                    app.FWHMLorentzEditField.Enable='off';
                    app.FWHMLorentzEditFieldLabel.Enable='off';
                case 'Voigt'
                    app.DiameterEditField.Enable='off';
                    app.DiameterEditFieldLabel.Enable='off';
                    app.WidthEditField.Enable='off';
                    app.WidthEditFieldLabel.Enable='off';
                    app.FWHMEditField.Enable='off';
                    app.FWHMEditFieldLabel.Enable='off';
                    app.FWHMGaussEditField.Enable='on';
                    app.FWHMGaussEditFieldLabel.Enable='on';
                    app.FWHMLorentzEditField.Enable='on';
                    app.FWHMLorentzEditFieldLabel.Enable='on';
            end
        end

        % Menu selected function: OffMenu
        function OffMenuSelected(app, event)
            wb=uiprogressdlg(app.DiffuserGUI,'Title','Closing parallel computing...','Indeterminate','on');
            
            delete(gcp("nocreate"));
            app.OffMenu.Checked="on";
            app.OnMenu.Checked="off";
            app.ispar=0;

            close(wb)
            uialert(app.DiffuserGUI,'Successfully close parallel computing','Note')
        end

        % Menu selected function: OnMenu
        function OnMenuSelected(app, event)
            wb=uiprogressdlg(app.DiffuserGUI,'Title','Starting parallel computing...','Indeterminate','on');
            
            poolobj = gcp('nocreate');% If no pool, create new one.
            if isempty(poolobj)
                poolobj = parpool;
            end
            app.OnMenu.Checked='on';
            app.OffMenu.Checked="off";
            app.ispar=1;

            close(wb)
            uialert(app.DiffuserGUI,'Successfully start parallel computing','Note')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiffuserGUI = uifigure('Visible', 'off');
            app.DiffuserGUI.Name = 'Diffuser';
            app.SettingsMenu = uimenu(app.DiffuserGUI);
            app.SettingsMenu.Text = 'Settings';
            app.ParallelcomputingMenu = uimenu(app.SettingsMenu);
            app.ParallelcomputingMenu.Text = 'Parallel computing';
            app.OffMenu = uimenu(app.ParallelcomputingMenu);
            app.OffMenu.MenuSelectedFcn = createCallbackFcn(app, @OffMenuSelected, true);
            app.OffMenu.Checked = 'on';
            app.OffMenu.Text = 'Off';
            app.OnMenu = uimenu(app.ParallelcomputingMenu);
            app.OnMenu.MenuSelectedFcn = createCallbackFcn(app, @OnMenuSelected, true);
            app.OnMenu.Text = 'On';
            app.curvefitting = uiaxes(app.DiffuserGUI);
            title(app.curvefitting, 'Diffusion profile')
            xlabel(app.curvefitting, 'Distance (m)')
            ylabel(app.curvefitting, 'Composition')
            app.time = uiaxes(app.DiffuserGUI);
            title(app.time, 'RSS against time')
            xlabel(app.time, 'Modeled time')
            ylabel(app.time, 'Residual sum of squares (RSS)')
            app.errorcontributions = uiaxes(app.DiffuserGUI);
            title(app.errorcontributions, 'Uncertainty budget')
            ylabel(app.errorcontributions, 'ln[t] uncertainty budget (%)')
            app.TabGroup = uitabgroup(app.DiffuserGUI);
            app.ImportdataTab = uitab(app.TabGroup);
            app.ImportdataTab.Title = '1. Import data';
            app.SelectfileButton = uibutton(app.ImportdataTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.CleartableButton = uibutton(app.ImportdataTab, 'push');
            app.CleartableButton.ButtonPushedFcn = createCallbackFcn(app, @CleartableButtonPushed, true);
            app.CleartableButton.Text = 'Clear table';
            app.UITable = uitable(app.ImportdataTab);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'};
            app.UITable.RowName = {};
            app.RandomButton = uibutton(app.ImportdataTab, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.text11 = uilabel(app.ImportdataTab);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Diffuser';
            app.Image = uiimage(app.ImportdataTab);
            app.Image.ImageSource = 'Diffuser.png';
            app.DataselectionPanel = uipanel(app.ImportdataTab);
            app.DataselectionPanel.Title = 'Data selection';
            app.DistanceunitDropDownLabel = uilabel(app.DataselectionPanel);
            app.DistanceunitDropDownLabel.Text = 'Distance unit';
            app.DistanceunitDropDown = uidropdown(app.DataselectionPanel);
            app.DistanceunitDropDown.Items = {'nm', 'um', 'mm', 'm'};
            app.DistanceunitDropDown.Value = 'um';
            app.DistanceDropDownLabel = uilabel(app.DataselectionPanel);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.DataselectionPanel);
            app.DistanceDropDown.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.DistanceDropDown.Value = 'Column 1';
            app.CompositionDropDownLabel = uilabel(app.DataselectionPanel);
            app.CompositionDropDownLabel.Text = 'Composition';
            app.CompositionDropDown = uidropdown(app.DataselectionPanel);
            app.CompositionDropDown.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.CompositionDropDown.Value = 'Column 2';
            app.CompositionuncertaintyDropDownLabel = uilabel(app.DataselectionPanel);
            app.CompositionuncertaintyDropDownLabel.Text = 'Composition uncertainty';
            app.CompositionuncertaintyDropDown = uidropdown(app.DataselectionPanel);
            app.CompositionuncertaintyDropDown.Items = {'None', 'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.CompositionuncertaintyDropDown.Value = 'None';
            app.InitialconditionsPanel = uipanel(app.ImportdataTab);
            app.InitialconditionsPanel.Title = 'Initial conditions';
            app.DistanceDropDown_2Label = uilabel(app.InitialconditionsPanel);
            app.DistanceDropDown_2Label.Text = 'Distance';
            app.DistanceDropDown_2 = uidropdown(app.InitialconditionsPanel);
            app.DistanceDropDown_2.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.DistanceDropDown_2.Value = 'Column 3';
            app.CompositionDropDown_2Label = uilabel(app.InitialconditionsPanel);
            app.CompositionDropDown_2Label.Text = 'Composition';
            app.CompositionDropDown_2 = uidropdown(app.InitialconditionsPanel);
            app.CompositionDropDown_2.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4'};
            app.CompositionDropDown_2.Value = 'Column 4';
            app.DistancegridEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.DistancegridEditFieldLabel.Text = 'Distance grid';
            app.DistancegridEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.DistancegridEditField.Limits = [0 Inf];
            app.DistancegridEditField.ValueDisplayFormat = '%.0f';
            app.DistancegridEditField.Value = 30;
            app.PlotButton = uibutton(app.ImportdataTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TemplateButton = uibutton(app.ImportdataTab, 'push');
            app.TemplateButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateButtonPushed, true);
            app.TemplateButton.Text = 'Template';
            app.DeconvolutionoptionalTab = uitab(app.TabGroup);
            app.DeconvolutionoptionalTab.Title = 'Deconvolution (optional)';
            app.BeamshapeButtonGroup = uibuttongroup(app.DeconvolutionoptionalTab);
            app.BeamshapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BeamshapeButtonGroupSelectionChanged, true);
            app.BeamshapeButtonGroup.Title = ' Beam shape';
            app.CircularButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.CircularButton.Icon = 'circular.jpg';
            app.CircularButton.Text = 'Circular';
            app.CircularButton.Value = true;
            app.RectangleButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.RectangleButton.Icon = 'rectangle.jpg';
            app.RectangleButton.Text = 'Rectangle';
            app.GaussButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.GaussButton.Icon = 'gauss.jpg';
            app.GaussButton.Text = 'Gauss';
            app.LorentzButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.LorentzButton.Icon = 'lorentz.jpg';
            app.LorentzButton.Text = 'Lorentz';
            app.VoigtButton = uitogglebutton(app.BeamshapeButtonGroup);
            app.VoigtButton.Icon = 'voigt.jpg';
            app.VoigtButton.Text = 'Voigt';
            app.DeconvoluteanalyticalbeameffectsCheckBox = uicheckbox(app.DeconvolutionoptionalTab);
            app.DeconvoluteanalyticalbeameffectsCheckBox.Text = 'Deconvolute analytical beam effects';
            app.DeconvolutionismodifiedfromLabel = uilabel(app.DeconvolutionoptionalTab);
            app.DeconvolutionismodifiedfromLabel.Text = 'Deconvolution is modified from';
            app.BeamsizePanel = uipanel(app.DeconvolutionoptionalTab);
            app.BeamsizePanel.Title = 'Beam size';
            app.fullwidthathalfmaximiumLabel = uilabel(app.BeamsizePanel);
            app.fullwidthathalfmaximiumLabel.Text = 'full width at half maximium ';
            app.DiameterEditFieldLabel = uilabel(app.BeamsizePanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.BeamsizePanel);
            app.WidthEditFieldLabel.Enable = 'off';
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.WidthEditField.Enable = 'off';
            app.FWHMEditFieldLabel = uilabel(app.BeamsizePanel);
            app.FWHMEditFieldLabel.Enable = 'off';
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.FWHMEditField.Enable = 'off';
            app.FWHMGaussEditFieldLabel = uilabel(app.BeamsizePanel);
            app.FWHMGaussEditFieldLabel.Enable = 'off';
            app.FWHMGaussEditFieldLabel.Text = 'FWHM Gauss';
            app.FWHMGaussEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.FWHMGaussEditField.Enable = 'off';
            app.FWHMLorentzEditFieldLabel = uilabel(app.BeamsizePanel);
            app.FWHMLorentzEditFieldLabel.Enable = 'off';
            app.FWHMLorentzEditFieldLabel.Text = 'FWHM Lorentz';
            app.FWHMLorentzEditField = uieditfield(app.BeamsizePanel, 'numeric');
            app.FWHMLorentzEditField.Enable = 'off';
            app.UnitDropDownLabel = uilabel(app.BeamsizePanel);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.BeamsizePanel);
            app.UnitDropDown.Items = {'nm', 'um', 'mm', 'm'};
            app.UnitDropDown.Value = 'um';
            app.Image_2 = uiimage(app.DeconvolutionoptionalTab);
            app.Image_2.ImageSource = 'Diffuser.png';
            app.Hyperlink = uihyperlink(app.DeconvolutionoptionalTab);
            app.Hyperlink.URL = 'https://doi.org/10.1371/journal.pone.0241788';
            app.Hyperlink.Text = 'Jollands, 2020, PLOS ONE';
            app.DiffusioncoefficientDTab = uitab(app.TabGroup);
            app.DiffusioncoefficientDTab.Title = '2. Diffusion coefficient (D)';
            app.Panel_1 = uipanel(app.DiffusioncoefficientDTab);
            app.Element = uidropdown(app.Panel_1);
            app.Element.Items = {'Ti', 'Al'};
            app.Element.ValueChangedFcn = createCallbackFcn(app, @ElementValueChanged, true);
            app.Element.Value = 'Ti';
            app.Reference = uidropdown(app.Panel_1);
            app.Reference.Items = {'Jollands et al. (2020)'};
            app.Reference.ValueChangedFcn = createCallbackFcn(app, @ReferenceValueChanged, true);
            app.Reference.Value = 'Jollands et al. (2020)';
            app.UITable2 = uitable(app.Panel_1);
            app.UITable2.ColumnName = {'ln[D0] (m2/s)'; '2σ ln[D0]'; 'Ea (kJ/mol)'; '2σ Ea'; 'Covariance (ln[D0],Ea)'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.ExamplesDropDownLabel = uilabel(app.Panel_1);
            app.ExamplesDropDownLabel.Text = 'Examples';
            app.ExamplesDropDown = uidropdown(app.Panel_1);
            app.ExamplesDropDown.Items = {'Quartz', 'Option 2', 'Option 3', 'Option 4'};
            app.ExamplesDropDown.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDownValueChanged, true);
            app.ExamplesDropDown.Value = 'Quartz';
            app.Panel_2 = uipanel(app.DiffusioncoefficientDTab);
            app.Panel_2.Enable = 'off';
            app.ExamplesDropDown_2Label = uilabel(app.Panel_2);
            app.ExamplesDropDown_2Label.Text = 'Examples';
            app.ExamplesDropDown_2 = uidropdown(app.Panel_2);
            app.ExamplesDropDown_2.Items = {'Olivine'};
            app.ExamplesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ExamplesDropDown_2ValueChanged, true);
            app.ExamplesDropDown_2.Value = 'Olivine';
            app.Element_2 = uidropdown(app.Panel_2);
            app.Element_2.Items = {'Fo global v1', 'Fo TaMED v1'};
            app.Element_2.ValueChangedFcn = createCallbackFcn(app, @Element_2ValueChanged, true);
            app.Element_2.Value = 'Fo global v1';
            app.Reference_2 = uidropdown(app.Panel_2);
            app.Reference_2.Items = {'Mutch et al. (2021)'};
            app.Reference_2.ValueChangedFcn = createCallbackFcn(app, @Reference_2ValueChanged, true);
            app.Reference_2.Value = 'Mutch et al. (2021)';
            app.barLabel = uilabel(app.Panel_2);
            app.barLabel.Text = 'bar';
            app.barLabel_2 = uilabel(app.Panel_2);
            app.barLabel_2.Text = 'bar';
            app.fO2EditFieldLabel = uilabel(app.Panel_2);
            app.fO2EditFieldLabel.Text = 'fO2';
            app.fO2EditField = uieditfield(app.Panel_2, 'numeric');
            app.fO2EditField.Limits = [0 Inf];
            app.fO2EditField.Value = 1.78e-10;
            app.PressureEditFieldLabel = uilabel(app.Panel_2);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.Panel_2, 'numeric');
            app.PressureEditField.Limits = [0 Inf];
            app.PressureEditField.Value = 20000;
            app.aSiO2EditFieldLabel = uilabel(app.Panel_2);
            app.aSiO2EditFieldLabel.Text = 'a[SiO2]';
            app.aSiO2EditField = uieditfield(app.Panel_2, 'numeric');
            app.aSiO2EditField.Limits = [0 1];
            app.aSiO2EditField.Value = 1;
            app.UITable4 = uitable(app.Panel_2);
            app.UITable4.ColumnName = {'a'; 'b'; 'c'; 'd'; 'e'; 'f'; 'g'};
            app.UITable4.RowName = {};
            app.Dequation = uilabel(app.Panel_2);
            app.Dequation.Text = 'ln[D]=';
            app.inplagioclaseLabel = uilabel(app.Panel_2);
            app.inplagioclaseLabel.Text = 'in plagioclase';
            app.XAnDropDownLabel = uilabel(app.Panel_2);
            app.XAnDropDownLabel.Text = 'XAn';
            app.XAnDropDown = uidropdown(app.Panel_2);
            app.XAnDropDown.Items = {'Column 1', 'Column 2', 'Column 3', 'Column 4', 'Column 5'};
            app.XAnDropDown.Value = 'Column 5';
            app.DisafunctionofTPfO2andcompositionCheckBox = uicheckbox(app.DiffusioncoefficientDTab);
            app.DisafunctionofTPfO2andcompositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisafunctionofTPfO2andcompositionCheckBoxValueChanged, true);
            app.DisafunctionofTPfO2andcompositionCheckBox.Text = 'D is a function of T, P, fO2, and composition.';
            app.UseasimpleArrheniusequationDD0expEaRTCheckBox = uicheckbox(app.DiffusioncoefficientDTab);
            app.UseasimpleArrheniusequationDD0expEaRTCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseasimpleArrheniusequationDD0expEaRTCheckBoxValueChanged, true);
            app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Text = 'Use a simple Arrhenius equation: D=D0*exp(-Ea/RT)';
            app.UseasimpleArrheniusequationDD0expEaRTCheckBox.Value = true;
            app.TimecalculationTab = uitab(app.TabGroup);
            app.TimecalculationTab.Title = '3. Time calculation';
            app.TemperatureconditionsPanel = uipanel(app.TimecalculationTab);
            app.TemperatureconditionsPanel.Title = 'Temperature conditions';
            app.CoolingpathDropDownLabel = uilabel(app.TemperatureconditionsPanel);
            app.CoolingpathDropDownLabel.Text = 'Cooling path';
            app.CoolingpathDropDown = uidropdown(app.TemperatureconditionsPanel);
            app.CoolingpathDropDown.Items = {'isothermal', 'linear cooling', 'exponential cooling', 'parabolic cooling'};
            app.CoolingpathDropDown.ValueChangedFcn = createCallbackFcn(app, @CoolingpathDropDownValueChanged, true);
            app.CoolingpathDropDown.Tooltip = {'Usually cooling rate (k) is unknown, Diffuser will try various cooling rate scenarios to identify suitable rates that can effectively lower the temperature to a threshold value capable of freezing the diffusion profile to the measured data'};
            app.CoolingpathDropDown.Value = 'isothermal';
            app.sigmaLabel = uilabel(app.TemperatureconditionsPanel);
            app.sigmaLabel.Text = '2 sigma';
            app.Label = uilabel(app.TemperatureconditionsPanel);
            app.Label.Text = '(℃)';
            app.TemperatureerrorEditFieldLabel = uilabel(app.TemperatureconditionsPanel);
            app.TemperatureerrorEditFieldLabel.Text = 'Temperature error';
            app.TemperatureerrorEditField = uieditfield(app.TemperatureconditionsPanel, 'numeric');
            app.TemperatureerrorEditField.ValueDisplayFormat = '%.2f';
            app.TemperatureerrorEditField.Value = 20;
            app.InitialtemperatureEditFieldLabel = uilabel(app.TemperatureconditionsPanel);
            app.InitialtemperatureEditFieldLabel.Text = 'Initial temperature';
            app.InitialtemperatureEditField = uieditfield(app.TemperatureconditionsPanel, 'numeric');
            app.InitialtemperatureEditField.ValueDisplayFormat = '%.2f';
            app.InitialtemperatureEditField.Value = 700;
            app.Coolingequation = uilabel(app.TemperatureconditionsPanel);
            app.Coolingequation.Tooltip = {'T is real-time temperature; T0 is the initial temperature; k is a constant decribing the cooling rate. Temperature unit is Kelvin for calculating k.'};
            app.Coolingequation.Text = 'T=T0';
            app.MonteCarlotrialsforestimatingtuncertaintyEditFieldLabel = uilabel(app.TimecalculationTab);
            app.MonteCarlotrialsforestimatingtuncertaintyEditFieldLabel.Text = 'Monte Carlo trials for estimating t uncertainty';
            app.MonteCarlotrialsEditField = uieditfield(app.TimecalculationTab, 'numeric');
            app.MonteCarlotrialsEditField.Limits = [1 Inf];
            app.MonteCarlotrialsEditField.ValueDisplayFormat = '%.0f';
            app.MonteCarlotrialsEditField.Value = 1000;
            app.PropagateDerrorCheckBox = uicheckbox(app.TimecalculationTab);
            app.PropagateDerrorCheckBox.Text = 'Propagate D error';
            app.CalculatetButton = uibutton(app.TimecalculationTab, 'push');
            app.CalculatetButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetButtonPushed, true);
            app.CalculatetButton.Text = 'Calculate t';
            app.SaveresultsButton = uibutton(app.TimecalculationTab, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.PropagatecurvefittingerrorCheckBox = uicheckbox(app.TimecalculationTab);
            app.PropagatecurvefittingerrorCheckBox.Text = 'Propagate curve fitting error';
            app.PropagatecurvefittingerrorCheckBox.Value = true;
            app.SavefiguresButton = uibutton(app.DiffuserGUI, 'push');
            app.SavefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiguresButtonPushed, true);
            app.SavefiguresButton.Text = 'Save figures';
            app.TtdistributionsLabel = uilabel(app.DiffuserGUI);
            app.TtdistributionsLabel.Text = 'T-t distributions';
            app.ThisisabetaversionLabel = uilabel(app.DiffuserGUI);
            app.ThisisabetaversionLabel.Text = 'This is a beta version.';
            app.BugreportscanbesenttowlgcugbeducnLabel = uilabel(app.DiffuserGUI);
            app.BugreportscanbesenttowlgcugbeducnLabel.Text = 'Bug reports can be sent to: wlg@cugb.edu.cn';
            app.VisitsLabel = uilabel(app.DiffuserGUI);
            app.VisitsLabel.Text = 'Visits = ';
            app.SeemanualButton = uibutton(app.DiffuserGUI, 'push');
            app.SeemanualButton.ButtonPushedFcn = createCallbackFcn(app, @SeemanualButtonPushed, true);
            app.SeemanualButton.Text = 'See manual';
            % Show the figure after all components are created
            app.DiffuserGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diffuser_numerical

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiffuserGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiffuserGUI)
        end
    end
end